classdef DUNA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LoadDatabaseButton              matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DeploymentTab                   matlab.ui.container.Tab
        OperatorsInitialsPanel          matlab.ui.container.Panel
        SetupEditField                  matlab.ui.control.EditField
        SetupEditFieldLabel             matlab.ui.control.Label
        SubmitDeploymentButton          matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        CommentsLabel                   matlab.ui.control.Label
        DeploymentPanel                 matlab.ui.container.Panel
        SVPtakenCheckBox                matlab.ui.control.CheckBox
        LongitudeEditField              matlab.ui.control.EditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.EditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        WaypointEditField               matlab.ui.control.EditField
        WaypointEditFieldLabel          matlab.ui.control.Label
        DeployedByEditField             matlab.ui.control.EditField
        DeployedByEditFieldLabel        matlab.ui.control.Label
        VesselEditField                 matlab.ui.control.EditField
        VesselEditFieldLabel            matlab.ui.control.Label
        ReleaserPanel                   matlab.ui.container.Panel
        ReleaseCodeEditField            matlab.ui.control.EditField
        ReleaseCodeEditFieldLabel       matlab.ui.control.Label
        ModelDropDown_releaserDep       matlab.ui.control.DropDown
        ModelDropDown_2Label            matlab.ui.control.Label
        CalibrationPanel                matlab.ui.container.Panel
        OperatorEditField               matlab.ui.control.EditField
        OperatorEditFieldLabel          matlab.ui.control.Label
        CalibrationCommentsEditField    matlab.ui.control.EditField
        CalibrationCommentsEditFieldLabel  matlab.ui.control.Label
        GetFilePathButton               matlab.ui.control.Button
        CalibrationFileLeaveblankifinformationisnotavailableEditField  matlab.ui.control.EditField
        CalibrationFileLeaveblankifinformationisnotavailableEditFieldLabel  matlab.ui.control.Label
        SignalRangesEditField           matlab.ui.control.EditField
        SignalRangesEditFieldLabel      matlab.ui.control.Label
        MeasuredRMSVEditField           matlab.ui.control.NumericEditField
        MeasuredRMSVEditFieldLabel      matlab.ui.control.Label
        CalibrationMicrophoneDropDown   matlab.ui.control.DropDown
        CalibrationMicrophoneDropDownLabel  matlab.ui.control.Label
        TimeUTCEditField                matlab.ui.control.EditField
        TimeUTCEditFieldLabel           matlab.ui.control.Label
        LoggerPanel                     matlab.ui.container.Panel
        IDEditField                     matlab.ui.control.EditField
        IDEditFieldLabel                matlab.ui.control.Label
        ClickDetectorCheckBox           matlab.ui.control.CheckBox
        HydrophoneTypeDropDown          matlab.ui.control.DropDown
        HydrophoneTypeDropDownLabel     matlab.ui.control.Label
        ResetClockSyncUTCLabel          matlab.ui.control.Label
        GPSTimeEditField                matlab.ui.control.EditField
        GPSTimeEditFieldLabel           matlab.ui.control.Label
        LoggerTimeEditField             matlab.ui.control.EditField
        LoggerTimeEditFieldLabel        matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        GainDropDown                    matlab.ui.control.DropDown
        GainDropDownLabel               matlab.ui.control.Label
        DutyCycleEditField              matlab.ui.control.EditField
        DutyCycleEditFieldLabel         matlab.ui.control.Label
        SampleRatekHzEditField          matlab.ui.control.NumericEditField
        SampleRatekHzEditFieldLabel     matlab.ui.control.Label
        HydrophoneIDEditField           matlab.ui.control.EditField
        HydrophoneIDEditFieldLabel      matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        DeploymentDateDatePicker        matlab.ui.control.DatePicker
        DeploymentDateDatePickerLabel   matlab.ui.control.Label
        StationIDDropDown               matlab.ui.control.DropDown
        StationIDDropDownLabel          matlab.ui.control.Label
        RetrievalTab                    matlab.ui.container.Tab
        MooringLostCheckBox             matlab.ui.control.CheckBox
        DataenteredByEditField          matlab.ui.control.EditField
        DataenteredByEditFieldLabel     matlab.ui.control.Label
        SubmitRetrievalButton           matlab.ui.control.Button
        DataOffloadPanel                matlab.ui.container.Panel
        AssumeUTCtimeCheck              matlab.ui.control.CheckBox
        ClockDriftinformationmissingCheckBox  matlab.ui.control.CheckBox
        IsthedatausableCheckBox         matlab.ui.control.CheckBox
        GetDataPathButton               matlab.ui.control.Button
        DataLocationEditField           matlab.ui.control.EditField
        DataLocationEditFieldLabel      matlab.ui.control.Label
        LoggerTimeUTCEditField          matlab.ui.control.EditField
        LoggerTimeUTCEditFieldLabel     matlab.ui.control.Label
        GPSTimeUTCEditField             matlab.ui.control.EditField
        GPSTimeUTCEditFieldLabel        matlab.ui.control.Label
        DataOffloadDateDatePicker       matlab.ui.control.DatePicker
        DataOffloadDateDatePickerLabel  matlab.ui.control.Label
        ClockDriftLabel                 matlab.ui.control.Label
        CommentsLabel_2                 matlab.ui.control.Label
        EditField_retComments           matlab.ui.control.EditField
        RetrievedByEditField            matlab.ui.control.EditField
        RetrievedByEditFieldLabel       matlab.ui.control.Label
        VesselEditField_ret             matlab.ui.control.EditField
        VesselEditField_2Label          matlab.ui.control.Label
        RetrievalTimeUTCEditField       matlab.ui.control.EditField
        RetrievalTimeUTCEditFieldLabel  matlab.ui.control.Label
        RetrievalDateDatePicker         matlab.ui.control.DatePicker
        RetrievalDateDatePickerLabel    matlab.ui.control.Label
        DeploymentIDDropDown            matlab.ui.control.DropDown
        DeploymentIDDropDownLabel       matlab.ui.control.Label
        StationIDDropDown_ret           matlab.ui.control.DropDown
        StationIDDropDown_2Label        matlab.ui.control.Label
        QualityControlTab               matlab.ui.container.Tab
        FinalizedDataButton             matlab.ui.control.Button
        DeploymentDateRangeLabel        matlab.ui.control.Label
        UpdateDataRangeButton           matlab.ui.control.Button
        DateSlider                      matlab.ui.control.RangeSlider
        DeploymentParametersPanel       matlab.ui.container.Panel
        DataEndsEditField               matlab.ui.control.EditField
        DataEndsEditFieldLabel          matlab.ui.control.Label
        DataStartsEditField             matlab.ui.control.EditField
        DataStartsEditFieldLabel        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LoadDeploymentButton            matlab.ui.control.Button
        DeploymentIDDropDown_QAQC       matlab.ui.control.DropDown
        DeploymentIDDropDown_2Label     matlab.ui.control.Label
        StationIDDropDown_Q             matlab.ui.control.DropDown
        StationIDDropDown_3Label        matlab.ui.control.Label
        LoadDeploymentforQAQCLabel      matlab.ui.control.Label
        ForSoundTrapInstrumentsST600DSGetcLabel  matlab.ui.control.Label
        LogFileQAQCButton               matlab.ui.control.Button
        DataAnalysisTab                 matlab.ui.container.Tab
        ExternalExportPanel             matlab.ui.container.Panel
        ContactEmailEditField           matlab.ui.control.EditField
        ContactEmailEditFieldLabel      matlab.ui.control.Label
        ICESContinuousNoiseButton       matlab.ui.control.Button
        CompletedAnalysesListBox        matlab.ui.control.ListBox
        CompletedAnalysesListBoxLabel   matlab.ui.control.Label
        Step3StructtoTimeTableButton    matlab.ui.control.Button
        Step2PascalstoDecibleswithapplicationofClipLevelButton  matlab.ui.control.Button
        Step1DecidecadeDataExtractionButton  matlab.ui.control.Button
        DeploymentIDDropDown_DataA      matlab.ui.control.DropDown
        DeploymentIDDropDown_2Label_2   matlab.ui.control.Label
        StationIDDropDown_DataA         matlab.ui.control.DropDown
        StationIDDropDown_3Label_2      matlab.ui.control.Label
        LoadDeploymentButton_DataA      matlab.ui.control.Button
        EquipmentUpdateTab              matlab.ui.container.Tab
        SatelliteTagsPanel              matlab.ui.container.Panel
        ReleaserTablePanel              matlab.ui.container.Panel
        NotesEditField_2                matlab.ui.control.EditField
        NotesEditField_2Label           matlab.ui.control.Label
        UnitWorkingCheckBox_2           matlab.ui.control.CheckBox
        InputUpdateDataButton_releaser  matlab.ui.control.Button
        PurchasedByEditField_2          matlab.ui.control.EditField
        PurchasedByEditField_2Label     matlab.ui.control.Label
        IDnumberEditField_2             matlab.ui.control.EditField
        IDnumberEditField_2Label        matlab.ui.control.Label
        BrandDropDown_2                 matlab.ui.control.DropDown
        BrandDropDown_2Label            matlab.ui.control.Label
        ModelDropDown_3                 matlab.ui.control.DropDown
        ModelDropDown_3Label            matlab.ui.control.Label
        LoggerTablePanel                matlab.ui.container.Panel
        NotesEditField                  matlab.ui.control.EditField
        NotesEditFieldLabel             matlab.ui.control.Label
        UnitWorkingCheckBox             matlab.ui.control.CheckBox
        InputUpdateDataButton_logger    matlab.ui.control.Button
        RoughClipLevelEditField         matlab.ui.control.NumericEditField
        RoughClipLevelEditFieldLabel    matlab.ui.control.Label
        PurchasedByEditField            matlab.ui.control.EditField
        PurchasedByEditFieldLabel       matlab.ui.control.Label
        IDnumberEditField               matlab.ui.control.EditField
        IDnumberEditFieldLabel          matlab.ui.control.Label
        BrandDropDown                   matlab.ui.control.DropDown
        BrandDropDownLabel              matlab.ui.control.Label
        ModelDropDown_2                 matlab.ui.control.DropDown
        ModelDropDown_2Label_2          matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        LogFileQAQCTab                  matlab.ui.container.Tab
        SampCountPlot                   matlab.ui.control.UIAxes
        TempPlot                        matlab.ui.control.UIAxes
        ExternalBattPlot                matlab.ui.control.UIAxes
        InternalBattPlot                matlab.ui.control.UIAxes
        ViewDataTab                     matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ReportPlotsTab                  matlab.ui.container.Tab
        GanttButton                     matlab.ui.control.Button
        ViolinButton                    matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        database % Database to connect to.
        selectedPlot % Store the handle to the selected plot
        supportFunc = addpath(genpath('./supportFunctions/'));
        metadataLoc = 'O:\Tech_MSFD-deskriptor11-Danmark\Monitering\Data\__Metadata\__allMetaData\'  % Change based on where you want to save md.
    end
    
    methods (Access = public)
        
        function coord = coords2dec_deg(app,coordText)
            if isempty(strfind(coordText, '°'))
                coord = str2double(coordText);
            else
                degrees = str2double(extractBefore(coordText, '°'));
                decimal = str2double(extractAfter(coordText, '°'))/60;
                coord=degrees+decimal;
            end
            return 
        end
        
        
        function plotClicked(app, hObject, ~)
            app.selectedPlot = hObject;
            
        end

        function point = pointClicked(app, ~,eventData)
        % Extract the x-coordinate (datetime) of the selected point
        selectedX = eventdata.IntersectionPoint(1); % Assuming 1D plot

        % Convert the datetime value to the desired format (e.g., yyyy-MM-dd HH:mm:ss)
        formattedDatetime = datestr(selectedX, 'yyyy-MM-dd HH:mm:ss');
        end

    end
    
    methods (Access = public)
        
        function results = checkUID(app,UID,tableName)
            conn = sqlite(app.database);

            switch tableName
                case 'Deployment_Table'
                    cat = 'Deployment_ID';
                case 'Logger_Table'
                    cat = 'loggerUID';
                case 'Releaser_Table'
                    cat = 'releaserUID';
                case 'Satellite_Table'
                    cat = 'satUID';
            end

            sqlq = ['SELECT ' cat ' FROM ' tableName ' WHERE ' cat ' = "' UID '"'];

            results =fetch(conn, sqlq);
            close(conn)
            return

        end
    end
    
    methods (Access = private)
        
        function newDTall = clockDrift(app,syncGPSstart, syncGPSslut, syncLOGstart, syncLOGslut, DT_all, n)
                    %%Interpolate datetimes to match the GPS timestamps and account for clock
                    %drift in the recorders. Returns a vector of adjusted datetimes. 
                    
                    % Updated in March 2024 to work directly within DUNA.
                    % Emily T Griffiths, emilytgriffiths@ecos.au.dk
                    
                    %% Definition of variables:
                    
                        %syncGPSstart -  Should be present for all deployments. This was the
                        %time that the unit was synced with a GPS before deployment. This is
                        %the actual GPS time, as sometimes there can be a difference between
                        %the GPS time and the logger sync time, by a second or so, since Ocean
                        %Instruments takes it start time from the computer and not by manual
                        %input.
                        
                        %syncLOGstart - Should be present for all deployments. This was the
                        %time that the unit was synced with a GPS before deployment. This is
                        %the cooresponding Logger time.
                        % 
                        %syncGPSslut - Time on the unit when the data was offloaded according
                        %to the GPS time.
                        
                        %syncLOGslot - Time according to the logger when data was offloaded.
                       
                        
                        %DT_all - Datetime series you wish to adjust for Clock Drift, generally
                        %derived from the timestamps on the files.
                        
                        %n - length of vectors you would like to interpolate over. Default is
                        %5000.
    
            if isnat(syncGPSstart)
                error('Not enough information to adjust for Clock Drift. \nFunction requires time unit was synced with GPS time before deployment. \nInclude time syncGPSstart variable.')
            end
        
            if isnat(syncLOGstart)
                error('Not enough information to adjust for Clock Drift. \nFunction requires time unit was synced with GPS time before deployment. \nInclude time syncLOGstart variable.')
            end
        
            if isnat(syncGPSslut)
                error('Not enough information to adjust for Clock Drift. \nFunction requires either the GPS time the unit stopped, or the difference in seconds between the time the unit and the GPS time. \nInclude syncGPSslut variable.')
            end
            
            if isnat(syncLOGslut)
                error('Not enough information to adjust for Clock Drift. \nFunction requires internal time of deployed unit to calculate the difference in Clock Drift. \nInclude the syncLOGslut variable.')
            end


            if  nargin < 7 || isempty(n)
                n=5000;
            end
            
        
            %Create linear vectors of fixed length to interpolate on.
            timeGPSdiff=datenum(linspace(syncGPSstart,syncGPSslut,n));
            timeClockdiff=datenum(linspace(syncLOGstart,syncLOGslut,n));
            %Convert date times to datenum
            DTall_num=datenum(DT_all);
            %Interpolate!
            newDTall=datetime(interp1(timeClockdiff,timeGPSdiff,DTall_num),'ConvertFrom','datenum');
            return
        end
                   
        
        function [x, y] = selectSinglePoint(ax)
            % Get coordinates of all data points in the axes
            xyobj = findall(ax.Children, '-Property', 'xData');
            xydata = get(xyobj, {'XData', 'YData'});
            xydataMat = cell2mat(xydata');
            
            
            % Wait for user to click on a point
            [x, y] = ginput(1);


            % Find the closest data point to the clicked coordinates
            distances = sqrt((xydataMat(1, :) - x).^2 + (xydataMat(2, :) - y).^2);
            [~, closestIdx] = min(distances);
            
            % Return the coordinates of the closest point
            x = xydataMat(1, closestIdx);
            y = xydataMat(2, closestIdx);
        end
   
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");
            javaaddpath( 'supportFunctions\sqlite-jdbc-3.46.1.3.jar');
        end

        % Button pushed function: LoadDatabaseButton
        function LoadDatabaseButtonPushed(app, event)
            [filename, path, idx]=uigetfile('*.sqlite', ...
                'Select Metadata Database (sqlite)');
            app.database=fullfile(path, filename);
        end

        % Button pushed function: SubmitDeploymentButton
        function SubmitDeploymentButtonPushed(app, event)
            % Are you connected to the database?
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error")
            end

            % Basic station and deployment information
            station = app.StationIDDropDown.Value;
            depDate = app.DeploymentDateDatePicker.Value;
            depDst= datetime(depDate, 'Format','yyyyMMdd');
            depTime = datetime(app.TimeUTCEditField.Value, 'Format','HH:mm:ss');
            Deployment_ID = [station '_' char(depDst)];
            Datetime_Deployment = depDate + timeofday(depTime);
            Datetime_Deployment.Format = 'yyyy-MM-dd HH:mm:ss';
            mooringLat = coords2dec_deg(app,app.LatitudeEditField.Value);
            mooringLon = coords2dec_deg(app,app.LongitudeEditField.Value);
            Deployed_by = app.DeployedByEditField.Value;
            depShip = app.VesselEditField.Value;
            waypoint=app.WaypointEditField.Value;

            gain = app.GainDropDown.Value;
            dCycle  = app.DutyCycleEditField.Value;
            SampRate = app.SampleRatekHzEditField.Value;
            ClickDetectorOn=app.ClickDetectorCheckBox.Value;
            SVPtaken=app.SVPtakenCheckBox.Value;


            % Is this deployment already in the database?
            dbP =checkUID(app,Deployment_ID, 'Deployment_Table');

            if ~isempty(dbP) 
                fig=uifigure;
                uialert(fig, ['Error: Deployment ID ' Deployment_ID ' already exists.'], 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig));
            end

            % Releaser Check
            releaserTypes = app.ModelDropDown_releaserDep.Value  ;

            switch releaserTypes 
                case 'LRT (Sonardyne)'
                    prefix='SD_';
                case 'AR-60 (Sub Sea Sonic)'
                    prefix = 'SSS_';
                case 'R500 (Teledyne)'
                    prefix = 'TD_';
                case 'Ascent-AR (InnovaSea)'
                    prefix = 'ASC_';
            end

            releaserID = [prefix, char(app.ReleaseCodeEditField.Value)];

            dbR =checkUID(app,releaserID, 'Releaser_Table');

            if isempty(dbR) 
                fig=uifigure;
                uialert(fig, ['Error: releaser ID ' releaserID ' is not in database. Add information on releaser to database before adding deployment.'], 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig));
            end

            %  Logger Check

            loggerType = app.ModelDropDown.Value;
            loggerID = [extractBefore(loggerType, 4) '_' app.IDEditField.Value];
            hpType=app.HydrophoneTypeDropDown.Value;
            hpID = app.HydrophoneIDEditField.Value;

            dbL =checkUID(app,loggerID, 'Logger_Table');

            if isempty(dbL) 
                fig=uifigure;
                uialert(fig, ['Error: logger ID ' loggerID ' is not in database. Add information on logger to database before adding deployment.'], 'Database Error', "Icon","error", 'CloseFcn',@(h,e) close(fig));
            end

            % Mooring Sattag function not working right now. Will impliment
            % later -ETG

            mooringOut = 1;

            %% Calibration info

            calMicSerial=app.CalibrationMicrophoneDropDown.Value;  % AU currenly only has two of these, and their values are listed below.

            switch calMicSerial 
                case "245958"
                    sensVPa=0.012;
                    sensdB=-158.4163751;
                case "148209"
                    sensVPa=0.05;
                    sensdB=-146.0205999;
            end

            Vrms = app.MeasuredRMSVEditField.Value;
            toneRange = str2num(app.SignalRangesEditField.Value);  
            CaliFile = app.CalibrationFileLeaveblankifinformationisnotavailableEditField.Value;

            if isempty(CaliFile)

               ClipLevel = NaN;
               fig=uifigure;
               uialert(fig, 'Warning: No calibration file. Update database appropriately.','No Calibration Information', "Icon","warning", 'CloseFcn',@(h,e) close(fig));
            else

                sm = sensVPa *10^-6;
                Inputmic= 20*log10(Vrms/sm);
                
                % Read in the file
                [sig,fs] = audioread(CaliFile) ;
                % Filter it to the time you want to use.
                samples = [toneRange(1)*fs,toneRange(2)*fs];
                sigf=sig(samples(1):samples(2));
                %Bandpass filter between 100-1000 Hz.
                [sig1,~] = bandpass(sigf,[100 1000],fs);
                %Convert signal to dBs.
                signal =  20*log10(sqrt(mean(sig1.^2)));
                %Calculate the difference between the reference microphone and your
                %recorded calibration signal. 
                ClipLevel=Inputmic-signal;
            end

            % Clock Sync Info
            gDate = app.DateDatePicker.Value;
            lTime = datetime(app.LoggerTimeEditField.Value, 'Format','HH:mm:ss');
            gTime = datetime(app.GPSTimeEditField.Value,'Format','HH:mm:ss') ;
            syncGPSstart = gDate + timeofday(gTime);
            syncGPSstart.Format = 'yyyy-MM-dd HH:mm:ss';
            syncLOGstart = gDate + timeofday(lTime);
            syncLOGstart.Format = 'yyyy-MM-dd HH:mm:ss';
            Comments=app.EditField.Value;

           text = ['Station: ' station newline 'Deployment Date: ' char(Datetime_Deployment) newline 'Deployment ID: ' Deployment_ID newline 'Latitude: ' char(mat2str(round(mooringLat,3))) '  Longitude: ' char(mat2str(round(mooringLon,3))) newline 'Ship:' depShip newline 'Deployed By: ' Deployed_by newline 'Waypoint: ' waypoint newline 'SVP Taken: ' num2str(SVPtaken) newline 'Releaser ID: ' releaserID newline 'Logger ID: ' loggerID newline 'Hydrophone Type: ' hpType newline 'Hydrophone ID: ' mat2str(hpID) newline 'Calibration Mic: ' calMicSerial newline 'Clip Level: ' mat2str(round(ClipLevel,1)) newline 'Gain: ' gain newline 'Duty Cycle: ' dCycle newline 'Sample Rate: ' num2str(SampRate) newline 'Click Detector? ' num2str(ClickDetectorOn) newline  'GPS Time Sync, GPS: ' char(syncGPSstart) ' Logger: ' char(syncLOGstart) newline 'Is this information correct?'];


            answer = questdlg(text, ...
                'Summary Info', ...
                'Yes', 'No', 'Yes');

            switch answer       % Fill in with database write options later.
                case 'Yes'
          
                    conn = sqlite(app.database);
                    colTab = sqlfind(conn, 'Deployment_Table');
                    colNms = colTab.Columns{:};

                    calTab = sqlfind(conn, 'Logger_Cal_Table');
                    calNms = calTab.Columns{:};

                    sqlqueryR =strcat("SELECT * from Releaser_Table WHERE releaserUID = '",releaserID,"'");
                    relinfo = fetch(conn,sqlqueryR);

                    if height(relinfo)==0
                        fig=uifigure;
                        uialert(fig, 'Error: No Releaser Record. First update database.', 'Database Error', "Icon","error")
                    end

                    sqlqueryL =strcat("SELECT * from Logger_Table WHERE loggerUID = '",string(loggerID),"'");
                    loginfo = fetch(conn,sqlqueryL);

                    if height(loginfo)==0
                        fig=uifigure;
                        uialert(fig, 'Error: No Logger Record. First update database.', 'Database Error', "Icon","error")
                    end
                    
                    insert(conn, 'Deployment_Table', colNms,...
                        {Deployment_ID, station, char(Datetime_Deployment), mooringLat, mooringLon,...
                        Deployed_by, releaserID, loggerID, char(''), mooringOut, 'standby...', char(NaT), char(NaT),...
                        char(app.SetupEditField.Value), Comments, 'true'});
                    
                    if isempty(CaliFile)

                    else

                        [~,nm,~]=fileparts(CaliFile);
    
                        if strncmp(loggerType, 'SM', 2)
                            rawDate=extractAfter(nm,'_');
                            calibrationDate=datetime(rawDate, 'InputFormat','yyyyMMdd_HHmmss');
                        else    % Everyone else, as they all use the ST Host Software
                            rawDate=extractAfter(nm,'.');
                            calibrationDate=datetime(rawDate, 'InputFormat','yyMMddHHmmss');
                        end
    
                        calibrationDate.Format = 'yyyy-MM-dd HH:mm:ss';
    
                        calUID = char(strcat(loggerID, '_', string(datetime(calibrationDate, 'Format','yyyy-MM'))));
                        sqlqueryC =strcat("SELECT * from Logger_Cal_Table WHERE calUID = '",string(calUID),"'");
                        calinfo = fetch(conn,sqlqueryC);
    
                        if height(calinfo)>0
                            calUID=strcat(calUID,'B');
                        end
    
                        insert(conn, 'Logger_Cal_Table', calNms,...
                            {calUID, loggerID, app.HydrophoneIDEditField.Value, 'AU', char(calibrationDate), app.OperatorEditField.Value,...
                            app.ModelDropDown.Value,app.IDEditField.Value, '','','250 Hz','3D Printed at AU', '120 dB re. 1 μPa','Danish',calMicSerial,...
                            sensVPa, Vrms, '','','','','',ClipLevel, char(datetime('today')), app.CalibrationCommentsEditField.Value,...
                            app.SignalRangesEditField.Value, CaliFile})
    
                        close(conn)
                    end

                    fname=[app.metadataLoc station '\' Deployment_ID '.mat'];
                    save(fname, 'Deployment_ID', 'station','Datetime_Deployment','mooringLon','mooringLat','mooringOut', 'Deployed_by', 'depShip',...
                        'waypoint','gain','dCycle','SampRate','ClickDetectorOn','SVPtaken','releaserID','loggerID','loggerType','hpID','hpType',...
                        'calMicSerial','sensdB','sensVPa','Vrms','toneRange','CaliFile','ClipLevel', 'syncLOGstart','syncGPSstart', 'Comments')


                case 'No'
                    disp('Try Again!')
            end
            %


        end

        % Button pushed function: GetFilePathButton
        function GetFilePathButtonPushed(app, event)
            [filename, path, idx]=uigetfile('*.wav', ...
                'Select Calibration Wav File');
            CaliFile=char([path filename]);
            app.CalibrationFileLeaveblankifinformationisnotavailableEditField.Value=CaliFile;
        end

        % Clicked callback: DeploymentIDDropDown
        function DeploymentIDDropDownClicked(app, event)
            
        end

        % Button pushed function: GetDataPathButton
        function GetDataPathButtonPushed(app, event)
            path=uigetdir('', ...
                'Select Data Directory');
            dataPath=char(path);
            app.DataLocationEditField.Value=dataPath;
        end

        % Button pushed function: SubmitRetrievalButton
        function SubmitRetrievalButtonPushed(app, event)
            % Collect Retrieval information
            Deployment_ID = app.DeploymentIDDropDown.Value;
            retShip = app.VesselEditField_ret.Value;
            Retrieved_by=app.RetrievedByEditField.Value;
            good = app.IsthedatausableCheckBox.Value;
            station = app.StationIDDropDown_ret.Value;
            Comments2=app.EditField_retComments.Value;

            if true(app.MooringLostCheckBox.Value)
                mooringOut = true;
                good=false;
                dataPath='N/A';
                clockdriftinfo = false;
                syncLOGslut = NaT;
                syncGPSslut = NaT;
                Datetime_Retrieval=NaT;

                text = ['Deployment ID: ' Deployment_ID newline 'Retrieval Datetime: ' char(NaT) newline 'Vessel: ' retShip newline 'Retrieved By: ' Retrieved_by newline 'Is Data Usable?: No' newline 'Is this information correct?'];

            answer = questdlg(text, ...
                'Summary Info', ...
                'Yes', 'No', 'Yes');
            else
    
                retDate = app.RetrievalDateDatePicker.Value;
                retTime = datetime(app.RetrievalTimeUTCEditField.Value, 'Format','HH:mm:ss');
                Datetime_Retrieval = retDate + timeofday(retTime);
                Datetime_Retrieval.Format = 'yyyy-MM-dd HH:mm:ss';
    
                dataPath = app.DataLocationEditField.Value;
    
                mooringOut = false;
    
                % Clock Sync Info
                clockdriftinfo=not(app.ClockDriftinformationmissingCheckBox.Value);

                if true(clockdriftinfo)
                    gDate = app.DataOffloadDateDatePicker.Value;
                    lTime = datetime(app.LoggerTimeUTCEditField.Value, 'Format','HH:mm:ss');
                    gTime = datetime(app.GPSTimeUTCEditField.Value,'Format','HH:mm:ss') ;
                    syncGPSslut = gDate + timeofday(gTime);
                    syncGPSslut.Format = 'yyyy-MM-dd HH:mm:ss';
                    syncLOGslut = gDate + timeofday(lTime);
                    syncLOGslut.Format = 'yyyy-MM-dd HH:mm:ss';
                else
                    syncGPSslut=NaT;
                    syncLOGslut=NaT;
                    
                end    
                Comments2=app.EditField_retComments.Value;

                text = ['Deployment ID: ' Deployment_ID newline 'Retrieval Datetime: ' char(Datetime_Retrieval) newline 'Vessel: ' retShip newline 'Retrieved By: ' Retrieved_by newline 'Data Time offload, GPS: ' char(syncGPSslut) ' Logger: ' char(syncLOGslut) newline 'Data Location: ' dataPath newline 'Is Data Usable?: ' num2str(good) newline 'Clock Drift info present: ' num2str(clockdriftinfo) newline 'Comments: ' Comments2 newline 'Is this information correct?'];
    
                answer = questdlg(text, ...
                    'Summary Info', ...
                    'Yes', 'No', 'Yes');
            end

            switch answer       % Fill in with database write options later.
                case 'Yes'


                    fname=[app.metadataLoc station '\' Deployment_ID '.mat'];
                    save(fname, 'Datetime_Retrieval','good','clockdriftinfo', 'retShip','mooringOut','Retrieved_by','dataPath','syncLOGslut','syncGPSslut', '-append')

                    try
                        conn = database('','','','org.sqlite.JDBC', ['jdbc:sqlite:' app.database]);
                    catch
                        conn = sqlite(app.database);
                    end
                    
                    colTab = fetch(conn, strcat("SELECT * FROM Deployment_Table WHERE Deployment_ID = '",string(Deployment_ID),"'"));

                    datTab = sqlfind(conn, 'Logger_Data_Table');
                    datNms = datTab.Columns{:};                    
                    
                    sqlqueryC =strcat("SELECT * from Logger_Cal_Table WHERE loggerUID = '",colTab.loggerUID,"'");
                    allCalis = flip(fetch(conn,sqlqueryC));

                    if isempty(allCalis)
                        error('No calibration ID. Update database.')
                    end
                 
                    items = datetime(cellfun(@(x){x(1:10)}, allCalis.calibrationDate));

                    [indx, ~] = listdlg('ListString', items, ...
                         'SelectionMode', 'single', ...
                         'PromptString', strcat("Select Calbration Date for: ", Deployment_ID), ...
                         'ListSize', [250, 100]);
                 

                    thisCali = allCalis(indx, :);
                 
                    colTab.mooringOut=false;
                    colTab.mooringLost=false;
                    colTab.Datetime_Retrieval=char(Datetime_Retrieval);
                    colTab.EnteredBy=app.DataenteredByEditField.Value;
                    colTab.good=good;

                    
                    %%%

                    if true(good)

                        data = load([app.metadataLoc station '\' Deployment_ID '.mat']);

                        %%Read in files%%
                        %Collect all the files in a directory.
                        fulldir = dir([dataPath '\*.wav']);
                        allFiles={fulldir.name}';
            
                        %%Currently set up for Wildlife Acoustics, Loggerhead Instruments, and
                        %%Ocean Instruments only. It is important that this collects the date
                        %%correctly, which is why it is in this script. Alter if necessary.
                        if strncmp(colTab.loggerUID,'SM',2)  %  Wildlife Acoutics
                            rawDate=extractBetween(allFiles,'_', '.');
                            DT_all=datetime(rawDate, 'InputFormat','yyyyMMdd_HHmmss');
                        else    % Everyone else, as they all use the ST Host Software
                            rawDate=extractBetween(allFiles,'.', '.');
                            DT_all=datetime(rawDate, 'InputFormat','yyMMddHHmmss');
                        end
            
                        %If there is clock drift data available, adjust the timestamps of our data
                        %to adjust for clock drift. 
                        if clockdriftinfo == 0
                            newDTall = DT_all;
                        else
                            newDTall = clockDrift(app, data.syncGPSstart, data.syncGPSslut, data.syncLOGstart, data.syncLOGslut, DT_all);
                        end
        
                        if app.AssumeUTCtimeCheck.Value==1
                            tz='UTC';
                            newDTall.TimeZone=tz;
        
                            fname=[app.metadataLoc station '\' Deployment_ID '.mat'];
                            save(fname, 'tz', '-append');
        
                        end
             
                        
                        %Date of the First and Last file in directory compared to the date of the
                        %deployment and retrieval.  Collect only the deployment data.
                        
                        DTfirstFile=min(newDTall);
                        DTlastFile=max(newDTall);
                        
                        %What file starts useful data? Was the unit turned on before deployment or
                        %after? Did the logger turn off before or after retrieval? These if
                        %statements grab only the data that is useful from the deployment, and
                        %discards the rest so it isn't included in this analysis.
                        if DTfirstFile > data.Datetime_Deployment
                            DTstart=DTfirstFile;
                        else
                            DTstart=data.Datetime_Deployment;
                        end
                        
                        if DTlastFile < data.Datetime_Retrieval
                            DTslut=DTlastFile;
                        else
                            DTslut=data.Datetime_Retrieval;
                        end
            
                        % Add two hour boundries on either side, since TZ has not been
                        % calculated yet.
            
                        DTstartPlus = DTstart - hours(2);
                        DTslutPlus = DTslut + hours(2);
                        
                        %Index your datetime based on these parameters.
                        DTinx=(newDTall >= DTstartPlus & newDTall <= DTslutPlus);
        
                        %  All file Data
        
                        uncroppedData = struct2table(fulldir);
                        
                        uncroppedData.orignalDate=DT_all;
                        uncroppedData.clockDriftDate=newDTall;
                        
                        %Select only files with data for futher analysis. 
                        depwavFiles = uncroppedData(DTinx,:);
        
                        fname=[app.metadataLoc station '\' Deployment_ID '.mat'];
                        save(fname, "DTstart", "DTslut", "uncroppedData", "depwavFiles", '-append');
        
                        %%%

                        colTab.Datetime_EndUsableData=char(DTslut);

                        try
                            update(conn, 'Deployment_Table', colTab.Properties.VariableNames, colTab(1,:), strcat("WHERE Deployment_ID = '",string(Deployment_ID),"'"));
                        catch

                            rf=rowfilter('Deployment_ID');
                            rf = rf.Deployment_ID== Deployment_ID;
            
                            sqlupdate(conn,'Deployment_Table', colTab(:,10:end),rf);
                        end

                                    
                        dataTab = fetch(conn, strcat("SELECT * FROM Logger_Data_Table WHERE LoggerDataUID = '",string(loggerdatID),"'"));
                 

                        if isempty(dataTab)
                            try 
                                insert(conn, 'Logger_Data_Table', datNms,...
                                {loggerdatID, Deployment_ID,colTab.loggerUID, dataPath, char(DTstartPlus), char(DTslutPlus), thisCali.calUID})
                            catch
                                t = cell2table({loggerdatID, Deployment_ID,colTab.loggerUID, dataPath, char(DTstartPlus), char(DTslutPlus), thisCali.calUID}, ...
                                    "VariableNames",datNms);
                                sqlwrite(conn, 'Logger_Data_Table', t)
                  
                            end
                        else
                            try
                                update(conn, 'Logger_Data_Table', datNms,...
                                {loggerdatID, Deployment_ID,colTab.loggerUID, dataPath, char(DTstartPlus), char(DTslutPlus), thisCali.calUID}, ...
                                strcat("WHERE Deployment_ID = '",string(Deployment_ID),"'"))
                            catch
                                rf=rowfilter('Deployment_ID');
                                rf = rf.Deployment_ID== Deployment_ID;

                                t = cell2table({loggerdatID, Deployment_ID,colTab.loggerUID, dataPath, char(DTstartPlus), char(DTslutPlus), thisCali.calUID}, ...
                                    "VariableNames",datNms);
                                sqlupdate(conn, 'Logger_Data_Table', t, rf)
                            end
                        end


                        close(conn)
    
                        data = load(fname);
                        data.Comments=[data.Comments, '; ' Comments2];
    
                        save(fname, '-struct', "data")
                    end
       
                case 'No'
                    disp('Try Again!')
            end
            

        end

        % Clicked callback: StationIDDropDown_ret
        function StationIDDropDown_Retrieval(app, event)
            item = event.InteractionInformation.Item;
             % Are you connected to the database?
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
            end
            
            value = app.StationIDDropDown_ret.Value;
            conn = sqlite(app.database);

            sqlq = ['SELECT Deployment_ID FROM Deployment_Table WHERE station = "' value '"'];

            results =fetch(conn, sqlq);
            close(conn)

            app.DeploymentIDDropDown.Items = sort(results{:,:}, 'descend');
        end

        % Button pushed function: LoadDeploymentButton
        function LoadDeploymentButtonPushed(app, event)

            addpath(genpath('O:\Tech_MSFD-deskriptor11-Danmark\Monitering\Analysis\scripts'));
            depID = app.DeploymentIDDropDown_QAQC.Value;
            station=app.StationIDDropDown_Q.Value;
            data = load([app.metadataLoc station '\' depID '.mat']);
            app.DataStartsEditField.Value = char(data.DTstart);
            app.DataEndsEditField.Value = char(data.DTslut);

        end

        % Button pushed function: LogFileQAQCButton
        function LogFileQAQCButtonPushed(app, event)

            % Are you connected to the database?
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
            end

            depID = app.DeploymentIDDropDown_QAQC.Value;
            station=app.StationIDDropDown_Q.Value;
            data = load([app.metadataLoc station '\' depID '.mat']);

            % Get data from database
            conn = sqlite(app.database);                
            sqlquery = strcat("SELECT * FROM Logger_Data_Table WHERE Deployment_ID = '",depID,"'");
            dataInfo= fetch(conn, sqlquery);
            close(conn)

            if strncmp(dataInfo.loggerUID,'DSG',3)
                timeformat='dd-MM-yyyy HH:mm:ss';
            elseif strncmp(dataInfo.loggerUID,'ST',2)
                timeformat='yyyy-MM-dd''T''HH:mm:ss';
            end
    

            if isfield(data, 'deplogTable')
                disp('standby...')
                deplogTable = data.deplogTable;
            else
                disp('work in progress')

                depfiles = data.depwavFiles;
                files=depfiles.name;
    
                logfiles = dir(strcat(data.dataPath, "\*.xml"));
    
                % Only use the log files associated with the deployment, based on time constraints.
                wfilebase=extractBefore(files,'.wav');
                lfilebase=extractBefore({logfiles.name}', '.log.xml');
                [~,fixd]=intersect(lfilebase,wfilebase);
    
                logfilesSub=logfiles(fixd,:);
    
                % Preallocate
                deplogData(length(logfilesSub)).OffloaderTimeZoneAttribute=deal([]);   
                deplogData(length(logfilesSub)).SamplingStartTimeUTCAttribute=deal([]);
                deplogData(length(logfilesSub)).SamplingStartTimeLocalAttribute=deal([]);
                deplogData(length(logfilesSub)).SamplingStopTimeUTCAttribute=deal([]);
                deplogData(length(logfilesSub)).SamplingStopTimeLocalAttribute=deal([]);
                deplogData(length(logfilesSub)).SamplingTimePeriodAttribute=deal([]);
                deplogData(length(logfilesSub)).CumulativeSamplingGapAttribute=deal([]);
                deplogData(length(logfilesSub)).SampleCountAttribute=deal([]);
                deplogData(length(logfilesSub)).SamplingStartTimeSubSAttribute=deal([]);
                deplogData(length(logfilesSub)).SampRate=deal([]);
                deplogData(length(logfilesSub)).nBits=deal([]);
                deplogData(length(logfilesSub)).HARDWARE_ID=deal([]);
                deplogData(length(logfilesSub)).INT_BATT=deal([]);
                deplogData(length(logfilesSub)).EX_BATT=deal([]);
                deplogData(length(logfilesSub)).TEMPERATURE=deal([]);
                deplogData(length(logfilesSub)).GAINAttribute=deal([]);
                deplogData(length(logfilesSub)).filename=deal([]);
                deplogData(length(logfilesSub)).timezone=deal([]);
                deplogData(length(logfilesSub)).notes=deal([]);
                % Add a field that allows the extraction of other data.
                storedFields=[string(fields(deplogData)); "AUDIO"];
    
 
                % Go through the Log files
                for xx = 1:length(logfilesSub)
                    try
                        tree = readstruct(strcat(dataInfo.dataPath, '\', logfilesSub(xx).name));
                    catch
                        error('Failed to read XML file %s.',logfilesSub(xx).name);
                    end
    
                    % First the ProcEvent branch to extract notes, offloader TZ,
                    % start and stop times of files, in both local and utc
                    % time, sampling gap, and sample count.

                    %Select PROCEvent 4, which is correct for reasons

    
                    for wh = 1:length(tree.PROC_EVENT)
                        if tree.PROC_EVENT(wh).IDAttribute == -1
                            deplogData(xx).notes = string(struct2cell(tree.PROC_EVENT(wh).SudarFileReader));
                            continue
                        end
                        if tree.PROC_EVENT(wh).IDAttribute > 4
                            continue
                        end
                        field=string(fieldnames(tree.PROC_EVENT(wh).WavFileHandler));
                        value=string(struct2cell(tree.PROC_EVENT(wh).WavFileHandler));
            
                        if field == "InfoAttribute"
                            continue
                        end
            
                        deplogData(xx).(field)=value;
            
                    end
    
                    % From the CFG tree, extract the sample and bit rates.
                    cid=find(strcmp(string({tree.CFG.FTYPEAttribute}), "wav"));
                    deplogData(xx).SampRate=tree.CFG(cid).FS;
                    deplogData(xx).nBits=tree.CFG(cid).NBITS;
            
                    %Get fields from the EVENT tree.
                    efields=fields(tree.EVENT);
    
    
                    % For each field, extract info, such as hardware ID,
                    % battery voltage, temperature, and gain.
                    for fd = 1:length(efields)
                        
                        field=string(efields(fd));
            
                        tf = storedFields(strcmpi(field,storedFields));
                        if isempty(tf) 
                            continue
                        end
            
                        if field == "InfoAttribute"
                            continue
                        end
            
                        if field=="HARDWARE_ID"
                            value=tree.EVENT(~ismissing([tree.EVENT.(field)])).(field);
            
                        elseif field=="AUDIO"
                            valuest={tree.EVENT(~cellfun(@ismissing,{tree.EVENT.(field)})).(field)};
            
            
                            for wh = 1:length(valuest)
                                fieldsub=string(fieldnames(valuest{wh}(1)));
                                tf= storedFields(strcmpi(fieldsub, storedFields));
                                if isempty(tf)
                                    continue
                                else
                                    field=tf;
                                end
                                value=string(struct2cell(valuest{wh}(1)));
            
                            end
            
                        else
                            valuest=struct2array(tree.EVENT(~cellfun(@ismissing,{tree.EVENT.(field)})).(field));
                            value=strcat(valuest(2));
                        
                        end
                            
                            deplogData(xx).(field)=value;
            
                    end
    
                    %Filename just for confirmation
                    deplogData(xx).filename=logfilesSub(xx).name;
    
    
                    % Extract Time Zone from sample and filename.
                    filetime=datetime(extractBetween(logfilesSub(xx).name, '.', '.log'), 'InputFormat','yyMMddHHmmss');
                    starttime=datetime(deplogData(xx).SamplingStartTimeUTCAttribute, 'InputFormat',timeformat);
            
            
                    dif=filetime-starttime;
                    dif = round(dif,'hour');
                    dif.Format='h';
                    offset=char(dif);
                    if length(offset)==4
                        offset=['+' offset];
                    end
            
                    deplogData(xx).timezone=['UTC' offset(1:2)];
                end
            
                deplogTable=struct2table(deplogData);

                tempTime = data.depwavFiles.clockDriftDate;
                timeshift=str2num(cell2mat(extractAfter(deplogTable.timezone, 'UTC')));

                tempTime=tempTime-hours(timeshift);
                tempTime.TimeZone='UTC';

                deplogTable.DTinUTC=tempTime;

                % Apply to DTstart and DTslut
                if sum(timeshift)>0
                    data.DTstart.TimeZone=tempTime(1).TimeZone;
                    data.DTslut.TimeZone=tempTime(end).TimeZone;
                else
                    data.DTstart.TimeZone='UTC';
                    data.DTslut.TimeZone = 'UTC';
                end


                % Probive warnings if these values are not as expected.
    
                if length(unique(vertcat(deplogData(:).GAINAttribute)))>1
                    fig=uifigure;
                    uialert(fig, 'Warning: More than one gain value. Investigate', 'Data Warning', "Icon","warning",'CloseFcn',@(h,e) close(fig))
                    close(fig)
                end
    
                if length(unique(vertcat(deplogData(:).HARDWARE_ID))) >1
                    fig=uifigure;
                    uialert(fig, 'Warning: More than one Hardware ID. Investigate', 'Data Warning', "Icon","warning",'CloseFcn',@(h,e) close(fig))
                    close(fig)
                end
    
    
                if length(unique(vertcat(deplogData(:).SampRate))) > 1
                    fig=uifigure;
                    uialert(fig, 'Warning: More than one sample rate recorded. Investigate', 'Data Warning', "Icon","warning",'CloseFcn',@(h,e) close(fig))
                    close(fig)
                end
    
    
                if length(unique(vertcat(deplogData(:).nBits))) > 1
                    fig=uifigure;
                    uialert(fig, 'Warning: More than one bit rate recorded. Investigate', 'Data Warning', "Icon","warning",'CloseFcn',@(h,e) close(fig))
                    close(fig)
                end
    
    
                srTemp=(unique(vertcat(deplogData(:).SampRate))/1000);
    
                if data.SampRate~=srTemp 
                    text = ['Log files report a different sample rate than previouls recorded. Do you wish to overwrite the sample rate?' newline 'Log File Sample Rate: ' num2str(srTemp) ' kHz (Recommended)' newline 'Previous Sample Rate: ' num2str(data.SampRate) ' kHz'];
                    answer = questdlg(text, ...
                        'Choose Sample Rate', ...
                        'Change to Log File', 'Keep Previous Value', 'Change to Log File');
    
                     switch answer       % Fill in with database write options later.
                        case 'Change to Log File'
                            fname=[depID '.mat'];
                            data = load(fname);
                            data.SampRate=srTemp;
                            save(fname, '-struct', "data")
               
                        case 'Keep Previous Value'
                            disp('Sample Rate may be incorrect.')
                     end
                end
                
    
                % This is a bit broken
                notes.note=(unique(vertcat(deplogData(:).notes)));
            
                if isempty(notes.note)
                else
    
                    disp(['The following errors were recorded:'; notes.note])
            
                    for n =1:length(notes)
                        idx = cellfun(@(c) any(strcmp(c,notes.note(n))), deplogTable.notes) ;
                        notes.loc(n)={idx};
                    end
                end
                 
                
                fname=[app.metadataLoc station '\' depID '.mat'];
                save(fname, "deplogTable", '-append');
            end


            %% PLOTS!

            %  Turn varialbes into numbers/dates to plot them
            deplogTable.SampleCountAttribute=str2double(deplogTable.SampleCountAttribute);
            deplogTable.INT_BATT=str2double(deplogTable.INT_BATT);
            deplogTable.EX_BATT=str2double(deplogTable.EX_BATT);
            deplogTable.TEMPERATURE=str2double(deplogTable.TEMPERATURE)/100;
            %deplogTable.SamplingStartTimeUTCAttribute=datetime(deplogTable.SamplingStartTimeUTCAttribute, 'InputFormat',timeformat);
            %deplogTable.notes=string(deplogTable.notes);

            %Subset to DT Range
            data.DTstart.TimeZone='UTC';
            data.DTslut.TimeZone='UTC';
            deplogTable=table2timetable(deplogTable, 'RowTimes','DTinUTC');
            tr = timerange(data.DTstart, data.DTslut);
            deplogTable=deplogTable(tr, :);

            plot(app.InternalBattPlot, deplogTable.DTinUTC,deplogTable.INT_BATT, 'color', 	"#0072BD")
       
            plot(app.ExternalBattPlot,deplogTable.DTinUTC,deplogTable.EX_BATT, 'color', "#77AC30")
        
            plot(app.SampCountPlot, deplogTable.DTinUTC,deplogTable.SampleCountAttribute, 'color', 	"#7E2F8E")
          
            plot(app.TempPlot,deplogTable.DTinUTC, deplogTable.TEMPERATURE, 'color', "#D95319")
  
    
            app.DateSlider.Limits=[1,height(deplogTable)];
            app.DateSlider.MajorTicks=1:400:height(deplogTable);
            dticks=deplogTable.DTinUTC;
            dticks.Format='yyyy-MM-dd';
            app.DateSlider.MajorTickLabels = string(dticks(1:400:end));
            %imshow(data_struct.(t.Properties.VariableNames{app.DateRangeSlider.Value}),"Parent",app.DCOffset)


        end

        % Button pushed function: UpdateDataRangeButton
        function UpdateDataRangeButtonPushed(app, event)
            value = app.DateSlider.Value;
            depID = app.DeploymentIDDropDown_QAQC.Value;
            station=app.StationIDDropDown_Q.Value;
            data = load([app.metadataLoc station '\' depID '.mat']);
            
            
            dtval = data.deplogTable.DTinUTC(round(value));

            app.DataStartsEditField.Value=string(dtval(1));
            app.DataEndsEditField.Value=string(dtval(2));

            % Update Database
            conn = sqlite(app.database);
            updateDates =strcat("UPDATE Logger_Data_Table SET DTend = '",string(dtval(2)),"', DTstart = '",string(dtval(1)),"' WHERE Deployment_ID = '",depID,"'");
            exec(conn,updateDates)
            close(conn)

            % Update the mat file
            data = load([app.metadataLoc station '\' depID '.mat']);
            data.DTstart = dtval(1);
            data.DTslut = dtval(2);
            fname=[app.metadataLoc station '\' depID '.mat'];
            save(fname, '-struct','data');
        

        end

        % Button pushed function: LoadDeploymentButton_DataA
        function LoadDeploymentButton_DataAPushed(app, event)
            app.CompletedAnalysesListBox.Items = {''};
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
            end

            conn = sqlite(app.database);
            value = app.DeploymentIDDropDown_DataA.Value;
            sqlq = ['SELECT analysisType FROM Logger_Analysis_Table WHERE Deployment_ID = "' value '"'];
            results =fetch(conn, sqlq);
            close(conn)

            app.CompletedAnalysesListBox.Multiselect = 'on';               
            app.CompletedAnalysesListBox.Items = {}; % Clear existing items

            if height(results)==0
                app.CompletedAnalysesListBox.Items = {'No Analysis Completed.'};
            else
                app.CompletedAnalysesListBox.Items=results.analysisType;
            end

            
        end

        % Clicked callback: StationIDDropDown_Q
        function StationIDDropDown_QClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
            end
            
            value = app.StationIDDropDown_Q.Value;
            conn = sqlite(app.database);

            sqlq = ['SELECT Deployment_ID FROM Deployment_Table WHERE station = "' value '"'];

            results =fetch(conn, sqlq);
            close(conn)

            app.DeploymentIDDropDown_QAQC.Items = sort(results{:,:}, 'descend');
        end

        % Clicked callback: StationIDDropDown_DataA
        function StationIDDropDown_DataAClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
            end
            
            value = app.StationIDDropDown_DataA.Value;
            conn = sqlite(app.database);

            sqlq = ['SELECT Deployment_ID FROM Deployment_Table WHERE station = "' value '"'];

            results =fetch(conn, sqlq);
            close(conn)

            app.DeploymentIDDropDown_DataA.Items = sort(results{:,:}, 'descend');
        end

        % Value changed function: DateSlider
        function DateSliderValueChanged(app, event)
            value = app.DateSlider.Value;

            depID = app.DeploymentIDDropDown_QAQC.Value;
            station = app.StationIDDropDown_Q.Value;
            data = load([app.metadataLoc station '\' depID '.mat']);
            dtval = data.deplogTable.DTinUTC(round(value));


            app.SampCountPlot.XLim=dtval;
            app.TempPlot.XLim=dtval;
            app.ExternalBattPlot.XLim=dtval;
            app.InternalBattPlot.XLim=dtval;
            
        end

        % Callback function
        function WavQAQCButtonPushed(app, event)
            % Are you connected to the database?
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
            end

            depID = app.DeploymentIDDropDown_QAQC.Value;
            station = app.StationIDDropDown_Q.Value;
            depData = load([app.metadataLoc station '\' depID '.mat']);

            % Get data from database
            javaaddpath("supportFunctions\sqlite-jdbc-3.46.1.3.jar")
            conn = database('','','','org.sqlite.JDBC', ['jdbc:sqlite:' app.database]);               
            sqlquery = strcat("SELECT * FROM Deployment_Table WHERE Deployment_ID = '",depID,"'");
            depInfo= fetch(conn, sqlquery);

            if depInfo.good == "false"
                print(strcat(depID, ' is not a usable deployment'))
                close(conn)
                return
            end

             if isfield(depData, 'QualityResults')
                disp('Loading Data...')
                QualityResults = depData.QualityResults;
             else

                disp('work in progress')
                sql2query = strcat("SELECT * FROM Logger_Data_Table WHERE Deployment_ID = '",depID,"'");
                dataInfo = fetch(conn, sql2query);
    
                depwavFiles = sortrows(depData.depwavFiles, 'clockDriftDate');
    
                filesdata = depwavFiles(depwavFiles.clockDriftDate >= dataInfo.DTstart & depwavFiles.clockDriftDate <= dataInfo.DTend,:);
    
                %% initialize variables - Numbers are leftover from BSH original code.
                Q_01(1:height(filesdata)) = NaN; % cannot read file
                Q_05(1:2,1:height(filesdata)) = NaN; % too much clipping
                Q_08o(1:height(filesdata)) = NaN; % DC-Offset - original
                Q_08m(1:height(filesdata)) = NaN; % DC-Offset - with sig=data-mean(data);
                Q_10o(1:height(filesdata)) = NaN; % rms differs from other recordings - original
                Q_10r(1:height(filesdata)) = NaN; % rms differs from other recordings - running/rolling average
                Q_13(1:height(filesdata)) = NaN; % check if data is stationary
                Q_14(1:height(filesdata)) = NaN; % all is good
                sr(1:height(filesdata))=NaN;
                duration(1:height(filesdata))=NaN;
                ts(1:height(filesdata))=NaN;
                stdrms(1:height(filesdata))=NaN;
                meanv(1:height(filesdata))=NaN;
                meanvDC(1:height(filesdata))=NaN;
    
                %% Get sample rate from first file
                filesr = audioinfo([char(filesdata.folder(1)) '\' char(filesdata.name(1))]).SampleRate;
                
                filesize = [];
    
                lim = (app.MaxofclippeddataEditField.Value/100);
    
                for kk = 1:height(filesdata)
                    tic
                    pause(5)
                    fileLoc = [char(filesdata.folder(kk)) '\' char(filesdata.name(kk))];
                    [Q_01(kk),data,sr(kk),duration(kk),ts(kk),stdrms(kk),meanv(kk),meanvDC(kk)] = Q01(fileLoc);
    
                    if Q_01(kk)==1
                        %% Fill in blanks if file can not be read
                        Q_05(:,kk) = NaN;
                        Q_13(kk) = NaN;
                    else
    
                    %% QC_05: Check if more than the limit of the data are clipped, from app.Maxofclippeddata.
                    Q_05(:,kk)=Q05(fileLoc,data,lim);
                    
                    %% QC_13: Check if data is stationary
                    Q_13(kk) = Q13(data,sr(kk),120); % 120 can be made variable, but for now we are standardizing it.
                   
                    % Remove data varibles
                    clear data flag perCl
                    ['file ' num2str(kk) ' out of ' num2str(height(filesdata))]
                    toc
                    end
                end


                %% DURATION NEEDS TO BE ADDED
                % % %Index the files that are less than 1% in length from the median duration.
                % % %Remove them from analysis. These files can be reported at the end of this
                % % %script.
                Qdur=~(duration > duration*0.01);
    
                %% QC_10: Check if rms differs significantly in recordings - plot - limits
                limi = str2double(app.RMSLimitDropDown.Value);
                Q_10o = Q10(stdrms,limi);   % original
                Q_10r = Q10meanStD(stdrms, limi,5); %Rolling average of 5 values
    
                %% Check if DC Offset leads to differences in levels > 1 dB
                Q_08o = Q08(meanv);     %original
                Q_08m = Q08(meanvDC);   %sig=data-mean(data);
    
    
                %% QC_14: Check if everything is okay is performed after QC_10 and is 
                Q_14 = Q14([Q_01;Q_05;Q_08m;Q_10r;Q_13;Qdur;]');
            
            
                QualityResults.Q_Clipping=Q_05;
                QualityResults.Q_DCoffsetOrg=Q_08o;
                QualityResults.Q_DCoffsetMean=Q_08m;
                QualityResults.Q_RMScheckOrg=Q_10o;
                QualityResults.Q_RMScheckRol=Q_10r;
                QualityResults.Q_Stationary=Q_13;
                QualityResults.Q_ALL=Q_14;
                QualityResults.duration=duration;
                QualityResults.samprate=ts;
                QualityResults.stdrms=stdrms;
                QualityResults.meanv=meanv;
                QualityResults.meanvDC=meanvDC;
                QualityResults.filesdata=filesdata;


                fname=[app.metadataLoc station '\' depID '.mat'];
                save(fname, "QualityResults", '-append');
                
             end
            
             %% Plots and lists.


            xlimswTZ=datetime(QualityResults.filesdata.clockDriftDate, 'TimeZone','UTC');

            idxm=find(logical(QualityResults.Q_DCoffsetMean));            
            plot(app.DCOffset,xlimswTZ,QualityResults.meanvDC,  'color',"blue")
            if sum(idxm)>0
                hold(app.DCOffset, 'on')
                dts = xlimswTZ(idxm);
                xline(app.DCOffset, dts, 'LineWidth',3,'Alpha',0.05, 'Color','red')
            end


            idxsd=find(logical(QualityResults.Q_RMScheckRol));            
            plot(app.RMS,xlimswTZ,QualityResults.stdrms , 'color', 	"green")
            if sum(idxsd)>0
                hold(app.DCOffset, 'on')    
                dts = xlimswTZ(idxsd);
                xline(app.DCOffset, dts, 'LineWidth',3,'Alpha',0.05, 'Color','red')
            end

            qstat=find(logical(QualityResults.Q_Stationary));
            qclip=find(logical(QualityResults.Q_Clipping));

            if length(qstat) == 0
               app.NonStationaryFilesTextArea.Value='All files Pass!';
            else
                app.NonStationaryFilesTextArea.Value = string(QualityResults.filesdata.name(qstat));
            end

             if length(qclip) == 0
               app.ClippedfilesTextArea.Value='All files Pass!';
            else
                app.ClippedfilesTextArea.Value = string(QualityResults.filesdata.name(qclip));
             end

             close(conn)


        end

        % Button pushed function: FinalizedDataButton
        function FinalizedDataButtonPushed(app, event)
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
            end

            depID = app.DeploymentIDDropDown_QAQC.Value;
            station = app.StationIDDropDown_Q.Value;
            depData = load([app.metadataLoc station '\' depID '.mat']);

            depwavFiles=depData.depwavFiles;
            depwavFiles.clockDriftDate.TimeZone='UTC';
            usableFiles = depwavFiles(depwavFiles.clockDriftDate >= depData.DTstart & depwavFiles.clockDriftDate <= depData.DTslut,:);
            fname=[app.metadataLoc station '\' depID '.mat'];
            save(fname, "usableFiles", '-append');
            disp('All done!')


        end

        % Button pushed function: InputUpdateDataButton_logger
        function InputUpdateDataButton_loggerPushed(app, event)
              if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
              end

            log.Brand = app.BrandDropDown.Value;
            log.Type = app.ModelDropDown_2.Value;
            unitID= app.IDnumberEditField.Value;
            loggerUID=string(extractBefore(log.Type, 4)) + '_' + string(unitID);

            conn = sqlite(app.database);
            query = strcat("SELECT * FROM Logger_Table WHERE loggerUID ='",string(loggerUID),"'");
            record = fetch(conn, query);

            log.loggerUID=loggerUID;
            log.purchasedBy = app.PurchasedByEditField.Value;
            log.unitStatus = app.UnitWorkingCheckBox.Value;
            log.unitID=unitID;
            log.clipLevel = app.RoughClipLevelEditField.Value;
            log.Notes = app.NotesEditField.Value;
            log.updateDate = string(date);

            log=struct2table(log, 'AsArray',true);
            oldvariables = log.Properties.VariableNames;
            newvariables = {'loggerUID','Brand','Type','unitID',  'purchasedBy', 'unitStatus','clipLevel','updateDate','Notes'};
            [~,LOCB] = ismember(newvariables,oldvariables);
            logTable=log(:,LOCB);


            if height(record)==0
                sqlwrite(conn, 'Logger_Table', logTable);
            else
                update(conn, 'Logger_Table', 'newvariables', logTable, strcat("WHERE loggerUID = '",string(loggerUID),"'"));
            end

        end

        % Button pushed function: InputUpdateDataButton_releaser
        function InputUpdateDataButton_releaserPushed(app, event)
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
            end

            log.Brand = app.BrandDropDown_2.Value;
            log.Type = app.ModelDropDown_3.Value;
            unitID= app.IDnumberEditField_2.Value;
            prex = extractBefore(log.Brand, 4);
            switch prex
                case 'Sub'
                    relUID='SSS_' + string(unitID);
                case 'Son'
                    relUID='SD_' + string(unitID);
                case 'Inn'
                    relUID='ASC_' + string(unitID);
                case 'Tel'
                    relUID='TEL_' + string(unitID);
            end

            conn = sqlite(app.database);
            query = strcat("SELECT * FROM Releaser_Table WHERE releaserUID ='",string(relUID),"'");
            record = fetch(conn, query);

            log.releaserUID=relUID;
            log.unitID = unitID;
            log.purchasedBy = app.PurchasedByEditField_2.Value;
            log.unitStatus = app.UnitWorkingCheckBox_2.Value;
            log.Notes = app.NotesEditField_2.Value;
            log.updateDate = string(date);

            log = struct2table(log, 'AsArray',true);


            oldvariables = log.Properties.VariableNames;
            newvariables = {'releaserUID','Type','Brand','unitID', 'purchasedBy', 'unitStatus','updateDate','Notes'};
            [~,LOCB] = ismember(newvariables,oldvariables);
            logTable=log(:,LOCB);


            if height(record)==0
                sqlwrite(conn, 'Releaser_Table', logTable);
            else
                update(conn, 'Releaser_Table', 'newvariables', logTable, strcat("WHERE releaserUID = '",string(relUID),"'"));
            end
        end

        % Value changed function: StationIDDropDown_DataA
        function StationIDDropDown_DataAValueChanged(app, event)
            value = app.StationIDDropDown_DataA.Value;
            
        end

        % Button pushed function: Step1DecidecadeDataExtractionButton
        function Step1DecidecadeDataExtractionButtonPushed(app, event)
             if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
             end

             depID = app.DeploymentIDDropDown_DataA.Value;
             station = app.StationIDDropDown_DataA.Value;
             depData = load([app.metadataLoc station '\' depID '.mat']);

             conn = sqlite(app.database);
             query = strcat("SELECT * FROM Logger_Analysis_Table WHERE Deployment_ID ='",string(depID),"' AND analysisType = 'DDEC-volts'");
             AnaRec = fetch(conn, query);
             close(conn)

             [outputLocation, ~, resultname, QualityResults] = decidecadeProcessing(depData);

             Q_08o(1:height(QualityResults.filesdata)) = NaN; % DC-Offset - original
             Q_08m(1:height(QualityResults.filesdata)) = NaN; % DC-Offset - with sig=data-mean(data);
             Q_10o(1:height(QualityResults.filesdata)) = NaN; % rms differs from other recordings - original
             Q_10r(1:height(QualityResults.filesdata)) = NaN; % rms differs from other recordings - running/rolling average
             Q_14(1:height(QualityResults.filesdata)) = NaN; % all is good


            %% QC_10: Check if rms differs significantly in recordings - plot - limits
            Q_10o = Q10(QualityResults.stdrms,10);   % original, limit default set to 10
            Q_10r = Q10meanStD(QualityResults.stdrms, 10,5); %Rolling average of 5 values

            %% Check if DC Offset leads to differences in levels > 1 dB
            Q_08o = Q08(QualityResults.meanv);     %original
            Q_08m = Q08(QualityResults.meanvDC);   %sig=data-mean(data);


            %% QC_14: Check if everything is okay is performed after QC_10 and is 
            Q_14 = Q14([QualityResults.Q_Readable;QualityResults.Q_Clipping;Q_08m;Q_10r;QualityResults.Q_Stationary;]');
        
        
            QualityResults.Q_DCoffsetOrg=Q_08o;
            QualityResults.Q_DCoffsetMean=Q_08m;
            QualityResults.Q_RMScheckOrg=Q_10o;
            QualityResults.Q_RMScheckRol=Q_10r;
            QualityResults.Q_ALL=Q_14;

            fname=[app.metadataLoc station '\' depID '.mat'];
            save(fname, "QualityResults", '-append');


            if height(AnaRec)==0

                 conn = sqlite(app.database);
                 query = strcat("SELECT * FROM Logger_Data_Table WHERE Deployment_ID ='",string(depID),"'");
                 record = fetch(conn, query);
                 close(conn)

                 ana.LoggerAnalysisUID = strcat(record.LoggerDataUID ,'DDECv_1');
                 ana.LoggerDataUID=record.LoggerDataUID;
                 ana.Deployment_ID = depID;
                 ana.loggerUID = record.loggerUID;
                 ana.analysisType = 'DDEC-volts';
                 ana.resultsPath = outputLocation;
                 ana.wavFiles = strjoin(depData.usableFiles.name,'; ');

                 ana.script = 'decidecadeProcessing.m';
                 ana.processingDate = char(datetime("now"));
                 ana.dataFile = resultname;
             %else  Add second run in here.
             end

             anT = struct2table(ana, "AsArray",true);
             conn = sqlite(app.database);
             sqlwrite(conn, 'Logger_Analysis_Table', anT);
             close(conn)


        end

        % Value changed function: OperatorEditField
        function OperatorEditFieldValueChanged(app, event)
            value = app.OperatorEditField.Value;
            
            
        end

        % Button pushed function: 
        % Step2PascalstoDecibleswithapplicationofClipLevelButton
        function Step2PascalstoDecibleswithapplicationofClipLevelButtonPushed(app, event)
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
            end
    
            ana = app.CompletedAnalysesListBox.Items;
            if isempty(ana(strcmpi(ana, 'DDEC-volts')))
                fig=uifigure;
                uialert(fig, 'Error: Complete decidecade analysis first', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
            end

            disp('Converting Data and writing to file.')

            
            depID = app.DeploymentIDDropDown_DataA.Value;
            station = app.StationIDDropDown_DataA.Value;

            conn = sqlite(app.database);
            query = strcat("SELECT * FROM Logger_Analysis_Table WHERE Deployment_ID ='",string(depID),"'");
            AnaLog = fetch(conn, query);
            close(conn)

            depData = load([app.metadataLoc station '\' depID '.mat']);

            [allnoise, resultname] = data2log(depData, AnaLog);

            if height(AnaLog(strcmp(AnaLog.analysisType, 'DDEC-dB_Allnoise'),:))>1
                n=height(AnaLog(strcmp(AnaLog.analysisType, 'DDEC-dB_Allnoise'),:))+1;
            else
                n=1;
            end

            record=AnaLog(strcmp(AnaLog.analysisType, 'DDEC-volts'),:);
            newr.LoggerAnalysisUID=strcat(record.LoggerDataUID, 'DDECdB_Allnoise_', num2str(n));
            newr.LoggerDataUID=record.LoggerDataUID;
            newr.Deployment_ID=depID;
            newr.loggerUID = record.loggerUID;
            newr.analysisType='DDEC-dB_Allnoise';
            newr.resultsPath=record.resultsPath;
            newr.wavFiles=record.wavFiles;
            newr.script='data2LogUnits_AU2024.m';
            newr.processingDate=char(datetime("now"));
            newr.dataFile=resultname;

            anT = struct2table(newr, "AsArray",true);
            conn = sqlite(app.database);
            sqlwrite(conn, 'Logger_Analysis_Table', anT);
            close(conn)

            disp('Complete!')


        end

        % Button pushed function: Step3StructtoTimeTableButton
        function Step3StructtoTimeTableButtonPushed(app, event)
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
            end
    
            ana = app.CompletedAnalysesListBox.Items;
            if isempty(ana(strcmpi(ana, 'DDEC-dB_Allnoise')))
                fig=uifigure;
                uialert(fig, 'Error: Apply clip level first', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
            end

            disp('Converting Data to a timetable.')
 
            depID = app.DeploymentIDDropDown_DataA.Value;
            station = app.StationIDDropDown_DataA.Value;

            conn = sqlite(app.database);
            query = strcat("SELECT * FROM Logger_Analysis_Table WHERE Deployment_ID ='",string(depID),"'");
            AnaLog = fetch(conn, query);
            close(conn)

            depData = load([app.metadataLoc station '\' depID '.mat']);
            filePath = unique(AnaLog.resultsPath);
            fileName = AnaLog.dataFile(AnaLog.analysisType=='DDEC-dB_Allnoise');
            saveTimetable=true;
            savePath= filePath;
                        
            [~, ~, saveName] = cell2timetable(filePath, fileName, saveTimetable, savePath, depID);


            if height(AnaLog(strcmp(AnaLog.analysisType, 'DDEC-dB_Allnoise_TT'),:))>1
                n=height(AnaLog(strcmp(AnaLog.analysisType, 'DDEC-dB_Allnoise_TT'),:))+1;
            else
                n=1;
            end

            record=AnaLog(strcmp(AnaLog.analysisType, 'DDEC-dB_Allnoise'),:);
            newr.LoggerAnalysisUID=strcat(record.LoggerDataUID, 'DDECdB_Allnoise_TT_', num2str(n));
            newr.LoggerDataUID=record.LoggerDataUID;
            newr.Deployment_ID=depID;
            newr.loggerUID = record.loggerUID;
            newr.analysisType='DDEC-dB_Allnoise_TT';
            newr.resultsPath=record.resultsPath;
            newr.wavFiles=record.wavFiles;
            newr.script='cell2timetable.m';
            newr.processingDate=char(datetime("now"));
            [~,nm,m]=fileparts(saveName);
            newr.dataFile=strcat(nm,m);

            anT = struct2table(newr, "AsArray",true);
            conn = sqlite(app.database);
            sqlwrite(conn, 'Logger_Analysis_Table', anT);
            close(conn)

            disp('Complete!')


        end

        % Button pushed function: ICESContinuousNoiseButton
        function ICESContinuousNoiseButtonPushed(app, event)
              
            if isempty(app.database)
                fig=uifigure;
                uialert(fig, 'Error: No Database Connected', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
            end

            ana = app.CompletedAnalysesListBox.Items;
            if isempty(ana(strcmpi(ana, 'DDEC-dB_Allnoise_TT')))
                fig=uifigure;
                uialert(fig, 'Error: Convert first to TimeTable to generate ICES output', 'Database Error', "Icon","error",'CloseFcn',@(h,e) close(fig))
                close(fig)
            end


            % text = 'Warning: This program is set up with Denmark/Aarhus Unversity defaults when generating the ICES formatting. If you are not working for AU, you need to change these settings within the code, function saveHDF5File_ICESformat.m. Do you wish to continue?';
            % 
            % answer = questdlg(text, ...
            %         'Do You Want to Continue?', ...
            %         'Yes', 'No', 'Yes');
            % 
            % switch answer
                % case 'No'
                %     disp('Peak into Code View.')
                % case 'Yes'
                    depID = app.DeploymentIDDropDown_DataA.Value;
                    station = app.StationIDDropDown_DataA.Value;
                    Email = app.ContactEmailEditField.Value;
                    disp(['Starting monthly data conversion to ICES Underwater Noise format -- h5 files, for ' char(depID)])


                    conn = sqlite(app.database);
                    query = strcat("SELECT * FROM Logger_Analysis_Table WHERE Deployment_ID ='",string(depID),"'");
                    AnaLog = fetch(conn, query);

                    dataUID = unique(AnaLog.LoggerDataUID);
                    calq1 = strcat("SELECT calUID FROM Logger_Data_Table WHERE LoggerDataUID = '", string(dataUID), "'");
                    calUID = fetch(conn, calq1);
                    calq2 = strcat("SELECT calibrationDate FROM Logger_Cal_Table WHERE calUID = '", calUID.calUID, "'");
                    calDate=fetch(conn, calq2);
                    close(conn)

                    depData = load([app.metadataLoc station '\' depID '.mat']);
                    filePath = unique(AnaLog.resultsPath);
                    fileName = AnaLog.dataFile(AnaLog.analysisType=='DDEC-dB_Allnoise_TT');

                    saveHDF5File_ICESformat(filePath, fileName, depData, depID, Email, calDate)
            

           % end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {727, 727};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {624, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Data';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.DeploymentTab = uitab(app.TabGroup);
            app.DeploymentTab.Title = 'Deployment';
            app.StationIDDropDownLabel = uilabel(app.DeploymentTab);
            app.StationIDDropDownLabel.Text = 'Station ID';
            app.StationIDDropDown = uidropdown(app.DeploymentTab);
            app.StationIDDropDown.Items = {'DKMst035', 'DKMst038', 'DKMst103', 'DKMst104', 'DKMst201', 'DKMst202Ø', 'DKMst202V'};
            app.StationIDDropDown.Placeholder = 'Select Station';
            app.StationIDDropDown.Value = 'DKMst035';
            app.DeploymentDateDatePickerLabel = uilabel(app.DeploymentTab);
            app.DeploymentDateDatePickerLabel.Text = 'Deployment Date';
            app.DeploymentDateDatePicker = uidatepicker(app.DeploymentTab);
            app.DeploymentDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.LoggerPanel = uipanel(app.DeploymentTab);
            app.LoggerPanel.Title = 'Logger';
            app.ModelDropDownLabel = uilabel(app.LoggerPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.LoggerPanel);
            app.ModelDropDown.Items = {'ST600', 'ST500', 'ST300', 'ST4300', 'DSG-ST', 'SM2M', 'SM3M', 'SLP (Sylence)', 'RESEA'};
            app.ModelDropDown.Value = 'ST600';
            app.HydrophoneIDEditFieldLabel = uilabel(app.LoggerPanel);
            app.HydrophoneIDEditFieldLabel.Text = 'Hydrophone ID';
            app.HydrophoneIDEditField = uieditfield(app.LoggerPanel, 'text');
            app.SampleRatekHzEditFieldLabel = uilabel(app.LoggerPanel);
            app.SampleRatekHzEditFieldLabel.Text = 'Sample Rate (kHz)';
            app.SampleRatekHzEditField = uieditfield(app.LoggerPanel, 'numeric');
            app.DutyCycleEditFieldLabel = uilabel(app.LoggerPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.LoggerPanel, 'text');
            app.DutyCycleEditField.Value = 'on/off';
            app.GainDropDownLabel = uilabel(app.LoggerPanel);
            app.GainDropDownLabel.Text = 'Gain';
            app.GainDropDown = uidropdown(app.LoggerPanel);
            app.GainDropDown.Items = {'High', 'Low', 'None'};
            app.GainDropDown.Value = 'High';
            app.DateDatePickerLabel = uilabel(app.LoggerPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.LoggerPanel);
            app.DateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.LoggerTimeEditFieldLabel = uilabel(app.LoggerPanel);
            app.LoggerTimeEditFieldLabel.Text = 'Logger Time';
            app.LoggerTimeEditField = uieditfield(app.LoggerPanel, 'text');
            app.LoggerTimeEditField.Value = 'HH:MM:SS';
            app.GPSTimeEditFieldLabel = uilabel(app.LoggerPanel);
            app.GPSTimeEditFieldLabel.Text = 'GPS Time';
            app.GPSTimeEditField = uieditfield(app.LoggerPanel, 'text');
            app.GPSTimeEditField.Value = 'HH:MM:SS';
            app.ResetClockSyncUTCLabel = uilabel(app.LoggerPanel);
            app.ResetClockSyncUTCLabel.Text = 'Reset Clock Sync (UTC)';
            app.HydrophoneTypeDropDownLabel = uilabel(app.LoggerPanel);
            app.HydrophoneTypeDropDownLabel.Text = 'Hydrophone Type';
            app.HydrophoneTypeDropDown = uidropdown(app.LoggerPanel);
            app.HydrophoneTypeDropDown.Items = {'in situ', 'HTI-96min', 'HTI-92wb', 'Reson'};
            app.HydrophoneTypeDropDown.Value = 'in situ';
            app.ClickDetectorCheckBox = uicheckbox(app.LoggerPanel);
            app.ClickDetectorCheckBox.Text = 'Click Detector?';
            app.IDEditFieldLabel = uilabel(app.LoggerPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.LoggerPanel, 'text');
            app.TimeUTCEditFieldLabel = uilabel(app.DeploymentTab);
            app.TimeUTCEditFieldLabel.Text = 'Time (UTC)';
            app.TimeUTCEditField = uieditfield(app.DeploymentTab, 'text');
            app.TimeUTCEditField.Value = 'HH:MM:SS';
            app.CalibrationPanel = uipanel(app.DeploymentTab);
            app.CalibrationPanel.Title = 'Calibration';
            app.CalibrationMicrophoneDropDownLabel = uilabel(app.CalibrationPanel);
            app.CalibrationMicrophoneDropDownLabel.Text = 'Calibration Microphone';
            app.CalibrationMicrophoneDropDown = uidropdown(app.CalibrationPanel);
            app.CalibrationMicrophoneDropDown.Items = {'245958', '148209'};
            app.CalibrationMicrophoneDropDown.Value = '245958';
            app.MeasuredRMSVEditFieldLabel = uilabel(app.CalibrationPanel);
            app.MeasuredRMSVEditFieldLabel.Text = 'Measured RMS  (V)';
            app.MeasuredRMSVEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.SignalRangesEditFieldLabel = uilabel(app.CalibrationPanel);
            app.SignalRangesEditFieldLabel.Text = 'Signal Range (s)';
            app.SignalRangesEditField = uieditfield(app.CalibrationPanel, 'text');
            app.CalibrationFileLeaveblankifinformationisnotavailableEditFieldLabel = uilabel(app.CalibrationPanel);
            app.CalibrationFileLeaveblankifinformationisnotavailableEditFieldLabel.Text = {'Calibration File'; '(Leave blank if information is not available.)'};
            app.CalibrationFileLeaveblankifinformationisnotavailableEditField = uieditfield(app.CalibrationPanel, 'text');
            app.GetFilePathButton = uibutton(app.CalibrationPanel, 'push');
            app.GetFilePathButton.ButtonPushedFcn = createCallbackFcn(app, @GetFilePathButtonPushed, true);
            app.GetFilePathButton.Text = 'Get File Path';
            app.CalibrationCommentsEditFieldLabel = uilabel(app.CalibrationPanel);
            app.CalibrationCommentsEditFieldLabel.Text = {'Calibration'; 'Comments'};
            app.CalibrationCommentsEditField = uieditfield(app.CalibrationPanel, 'text');
            app.OperatorEditFieldLabel = uilabel(app.CalibrationPanel);
            app.OperatorEditFieldLabel.Text = 'Operator';
            app.OperatorEditField = uieditfield(app.CalibrationPanel, 'text');
            app.OperatorEditField.ValueChangedFcn = createCallbackFcn(app, @OperatorEditFieldValueChanged, true);
            app.ReleaserPanel = uipanel(app.DeploymentTab);
            app.ReleaserPanel.Title = 'Releaser';
            app.ModelDropDown_2Label = uilabel(app.ReleaserPanel);
            app.ModelDropDown_2Label.Text = 'Model';
            app.ModelDropDown_releaserDep = uidropdown(app.ReleaserPanel);
            app.ModelDropDown_releaserDep.Items = {'LRT (Sonardyne)', 'AR-60 (Sub Sea Sonic)', 'Ascent-AR (InnovaSea)', 'R500 (Teledyne)'};
            app.ModelDropDown_releaserDep.Value = 'LRT (Sonardyne)';
            app.ReleaseCodeEditFieldLabel = uilabel(app.ReleaserPanel);
            app.ReleaseCodeEditFieldLabel.Text = 'Release Code';
            app.ReleaseCodeEditField = uieditfield(app.ReleaserPanel, 'text');
            app.DeploymentPanel = uipanel(app.DeploymentTab);
            app.DeploymentPanel.Title = 'Deployment';
            app.VesselEditFieldLabel = uilabel(app.DeploymentPanel);
            app.VesselEditFieldLabel.Text = 'Vessel';
            app.VesselEditField = uieditfield(app.DeploymentPanel, 'text');
            app.DeployedByEditFieldLabel = uilabel(app.DeploymentPanel);
            app.DeployedByEditFieldLabel.Text = 'Deployed By';
            app.DeployedByEditField = uieditfield(app.DeploymentPanel, 'text');
            app.WaypointEditFieldLabel = uilabel(app.DeploymentPanel);
            app.WaypointEditFieldLabel.Text = 'Waypoint';
            app.WaypointEditField = uieditfield(app.DeploymentPanel, 'text');
            app.LatitudeEditFieldLabel = uilabel(app.DeploymentPanel);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.DeploymentPanel, 'text');
            app.LatitudeEditField.Value = 'DD° MM.ddd';
            app.LongitudeEditFieldLabel = uilabel(app.DeploymentPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.DeploymentPanel, 'text');
            app.LongitudeEditField.Value = 'DD° MM.ddd';
            app.SVPtakenCheckBox = uicheckbox(app.DeploymentPanel);
            app.SVPtakenCheckBox.Text = 'SVP taken?';
            app.CommentsLabel = uilabel(app.DeploymentTab);
            app.CommentsLabel.Text = 'Comments';
            app.EditField = uieditfield(app.DeploymentTab, 'text');
            app.SubmitDeploymentButton = uibutton(app.DeploymentTab, 'push');
            app.SubmitDeploymentButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitDeploymentButtonPushed, true);
            app.SubmitDeploymentButton.Text = 'Submit Deployment';
            app.OperatorsInitialsPanel = uipanel(app.DeploymentTab);
            app.OperatorsInitialsPanel.Title = 'Operators Initials';
            app.SetupEditFieldLabel = uilabel(app.OperatorsInitialsPanel);
            app.SetupEditFieldLabel.Text = 'Setup';
            app.SetupEditField = uieditfield(app.OperatorsInitialsPanel, 'text');
            app.RetrievalTab = uitab(app.TabGroup);
            app.RetrievalTab.Title = 'Retrieval';
            app.StationIDDropDown_2Label = uilabel(app.RetrievalTab);
            app.StationIDDropDown_2Label.Text = 'Station ID';
            app.StationIDDropDown_ret = uidropdown(app.RetrievalTab);
            app.StationIDDropDown_ret.Items = {'DKMst035', 'DKMst038', 'DKMst103', 'DKMst104', 'DKMst201', 'DKMst202Ø', 'DKMst202V'};
            app.StationIDDropDown_ret.Placeholder = 'Select Station';
            app.StationIDDropDown_ret.ClickedFcn = createCallbackFcn(app, @StationIDDropDown_Retrieval, true);
            app.StationIDDropDown_ret.Value = 'DKMst035';
            app.DeploymentIDDropDownLabel = uilabel(app.RetrievalTab);
            app.DeploymentIDDropDownLabel.Text = 'Deployment ID';
            app.DeploymentIDDropDown = uidropdown(app.RetrievalTab);
            app.DeploymentIDDropDown.Items = {};
            app.DeploymentIDDropDown.Placeholder = 'Select Deployment';
            app.DeploymentIDDropDown.ClickedFcn = createCallbackFcn(app, @DeploymentIDDropDownClicked, true);
            app.DeploymentIDDropDown.Value = {};
            app.RetrievalDateDatePickerLabel = uilabel(app.RetrievalTab);
            app.RetrievalDateDatePickerLabel.Text = 'Retrieval Date';
            app.RetrievalDateDatePicker = uidatepicker(app.RetrievalTab);
            app.RetrievalTimeUTCEditFieldLabel = uilabel(app.RetrievalTab);
            app.RetrievalTimeUTCEditFieldLabel.Text = 'Retrieval Time (UTC)';
            app.RetrievalTimeUTCEditField = uieditfield(app.RetrievalTab, 'text');
            app.RetrievalTimeUTCEditField.Value = 'HH:MM:SS';
            app.VesselEditField_2Label = uilabel(app.RetrievalTab);
            app.VesselEditField_2Label.Text = 'Vessel';
            app.VesselEditField_ret = uieditfield(app.RetrievalTab, 'text');
            app.RetrievedByEditFieldLabel = uilabel(app.RetrievalTab);
            app.RetrievedByEditFieldLabel.Text = 'Retrieved By';
            app.RetrievedByEditField = uieditfield(app.RetrievalTab, 'text');
            app.EditField_retComments = uieditfield(app.RetrievalTab, 'text');
            app.CommentsLabel_2 = uilabel(app.RetrievalTab);
            app.CommentsLabel_2.Text = 'Comments';
            app.DataOffloadPanel = uipanel(app.RetrievalTab);
            app.DataOffloadPanel.Title = 'Data Offload';
            app.ClockDriftLabel = uilabel(app.DataOffloadPanel);
            app.ClockDriftLabel.Text = 'Clock Drift';
            app.DataOffloadDateDatePickerLabel = uilabel(app.DataOffloadPanel);
            app.DataOffloadDateDatePickerLabel.Text = 'Data OffloadDate';
            app.DataOffloadDateDatePicker = uidatepicker(app.DataOffloadPanel);
            app.DataOffloadDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.GPSTimeUTCEditFieldLabel = uilabel(app.DataOffloadPanel);
            app.GPSTimeUTCEditFieldLabel.Text = 'GPS Time (UTC)';
            app.GPSTimeUTCEditField = uieditfield(app.DataOffloadPanel, 'text');
            app.GPSTimeUTCEditField.Value = 'HH:MM:SS';
            app.LoggerTimeUTCEditFieldLabel = uilabel(app.DataOffloadPanel);
            app.LoggerTimeUTCEditFieldLabel.Text = 'Logger Time (UTC)';
            app.LoggerTimeUTCEditField = uieditfield(app.DataOffloadPanel, 'text');
            app.LoggerTimeUTCEditField.Value = 'HH:MM:SS';
            app.DataLocationEditFieldLabel = uilabel(app.DataOffloadPanel);
            app.DataLocationEditFieldLabel.Text = 'Data Location';
            app.DataLocationEditField = uieditfield(app.DataOffloadPanel, 'text');
            app.GetDataPathButton = uibutton(app.DataOffloadPanel, 'push');
            app.GetDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataPathButtonPushed, true);
            app.GetDataPathButton.Text = 'Get Data Path';
            app.IsthedatausableCheckBox = uicheckbox(app.DataOffloadPanel);
            app.IsthedatausableCheckBox.Text = 'Is the data usable?';
            app.ClockDriftinformationmissingCheckBox = uicheckbox(app.DataOffloadPanel);
            app.ClockDriftinformationmissingCheckBox.Text = 'Clock Drift information missing';
            app.AssumeUTCtimeCheck = uicheckbox(app.DataOffloadPanel);
            app.AssumeUTCtimeCheck.Text = {'Assume UTC time '; '(for SM, etc.)'};
            app.SubmitRetrievalButton = uibutton(app.RetrievalTab, 'push');
            app.SubmitRetrievalButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitRetrievalButtonPushed, true);
            app.SubmitRetrievalButton.Text = 'Submit Retrieval';
            app.DataenteredByEditFieldLabel = uilabel(app.RetrievalTab);
            app.DataenteredByEditFieldLabel.Text = 'Data entered By:';
            app.DataenteredByEditField = uieditfield(app.RetrievalTab, 'text');
            app.MooringLostCheckBox = uicheckbox(app.RetrievalTab);
            app.MooringLostCheckBox.Text = 'Mooring Lost';
            app.QualityControlTab = uitab(app.TabGroup);
            app.QualityControlTab.Title = 'Quality Control';
            app.LogFileQAQCButton = uibutton(app.QualityControlTab, 'push');
            app.LogFileQAQCButton.ButtonPushedFcn = createCallbackFcn(app, @LogFileQAQCButtonPushed, true);
            app.LogFileQAQCButton.Text = 'Log File QAQC';
            app.ForSoundTrapInstrumentsST600DSGetcLabel = uilabel(app.QualityControlTab);
            app.ForSoundTrapInstrumentsST600DSGetcLabel.Text = 'For SoundTrap Instruments (ST600, DSG, etc.)';
            app.LoadDeploymentforQAQCLabel = uilabel(app.QualityControlTab);
            app.LoadDeploymentforQAQCLabel.Text = 'Load Deployment for QAQC';
            app.StationIDDropDown_3Label = uilabel(app.QualityControlTab);
            app.StationIDDropDown_3Label.Text = 'Station ID';
            app.StationIDDropDown_Q = uidropdown(app.QualityControlTab);
            app.StationIDDropDown_Q.Items = {'DKMst035', 'DKMst038', 'DKMst103', 'DKMst104', 'DKMst201', 'DKMst202Ø', 'DKMst202V'};
            app.StationIDDropDown_Q.Placeholder = 'Select Station';
            app.StationIDDropDown_Q.ClickedFcn = createCallbackFcn(app, @StationIDDropDown_QClicked, true);
            app.StationIDDropDown_Q.Value = 'DKMst035';
            app.DeploymentIDDropDown_2Label = uilabel(app.QualityControlTab);
            app.DeploymentIDDropDown_2Label.Text = 'Deployment ID';
            app.DeploymentIDDropDown_QAQC = uidropdown(app.QualityControlTab);
            app.DeploymentIDDropDown_QAQC.Items = {};
            app.DeploymentIDDropDown_QAQC.Placeholder = 'Select Deployment';
            app.DeploymentIDDropDown_QAQC.Value = {};
            app.LoadDeploymentButton = uibutton(app.QualityControlTab, 'push');
            app.LoadDeploymentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDeploymentButtonPushed, true);
            app.LoadDeploymentButton.Text = 'Load Deployment';
            app.Label = uilabel(app.QualityControlTab);
            app.Label.Text = '';
            app.DeploymentParametersPanel = uipanel(app.QualityControlTab);
            app.DeploymentParametersPanel.Title = 'Deployment Parameters';
            app.DataStartsEditFieldLabel = uilabel(app.DeploymentParametersPanel);
            app.DataStartsEditFieldLabel.Text = 'Data Starts';
            app.DataStartsEditField = uieditfield(app.DeploymentParametersPanel, 'text');
            app.DataEndsEditFieldLabel = uilabel(app.DeploymentParametersPanel);
            app.DataEndsEditFieldLabel.Text = 'Data Ends';
            app.DataEndsEditField = uieditfield(app.DeploymentParametersPanel, 'text');
            app.DateSlider = uislider(app.QualityControlTab, 'range');
            app.DateSlider.ValueChangedFcn = createCallbackFcn(app, @DateSliderValueChanged, true);
            app.UpdateDataRangeButton = uibutton(app.QualityControlTab, 'push');
            app.UpdateDataRangeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDataRangeButtonPushed, true);
            app.UpdateDataRangeButton.Text = 'Update Data Range';
            app.DeploymentDateRangeLabel = uilabel(app.QualityControlTab);
            app.DeploymentDateRangeLabel.Text = 'Deployment Date Range';
            app.FinalizedDataButton = uibutton(app.QualityControlTab, 'push');
            app.FinalizedDataButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizedDataButtonPushed, true);
            app.FinalizedDataButton.Text = 'Finalized Data';
            app.DataAnalysisTab = uitab(app.TabGroup);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.LoadDeploymentButton_DataA = uibutton(app.DataAnalysisTab, 'push');
            app.LoadDeploymentButton_DataA.ButtonPushedFcn = createCallbackFcn(app, @LoadDeploymentButton_DataAPushed, true);
            app.LoadDeploymentButton_DataA.Text = 'Load Deployment';
            app.StationIDDropDown_3Label_2 = uilabel(app.DataAnalysisTab);
            app.StationIDDropDown_3Label_2.Text = 'Station ID';
            app.StationIDDropDown_DataA = uidropdown(app.DataAnalysisTab);
            app.StationIDDropDown_DataA.Items = {'DKMst035', 'DKMst038', 'DKMst103', 'DKMst104', 'DKMst201', 'DKMst202Ø', 'DKMst202V'};
            app.StationIDDropDown_DataA.ValueChangedFcn = createCallbackFcn(app, @StationIDDropDown_DataAValueChanged, true);
            app.StationIDDropDown_DataA.Placeholder = 'Select Station';
            app.StationIDDropDown_DataA.ClickedFcn = createCallbackFcn(app, @StationIDDropDown_DataAClicked, true);
            app.StationIDDropDown_DataA.Value = 'DKMst035';
            app.DeploymentIDDropDown_2Label_2 = uilabel(app.DataAnalysisTab);
            app.DeploymentIDDropDown_2Label_2.Text = 'Deployment ID';
            app.DeploymentIDDropDown_DataA = uidropdown(app.DataAnalysisTab);
            app.DeploymentIDDropDown_DataA.Items = {};
            app.DeploymentIDDropDown_DataA.Placeholder = 'Select Deployment';
            app.DeploymentIDDropDown_DataA.Value = {};
            app.Step1DecidecadeDataExtractionButton = uibutton(app.DataAnalysisTab, 'push');
            app.Step1DecidecadeDataExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @Step1DecidecadeDataExtractionButtonPushed, true);
            app.Step1DecidecadeDataExtractionButton.Text = 'Step 1 - Decidecade Data Extraction';
            app.Step2PascalstoDecibleswithapplicationofClipLevelButton = uibutton(app.DataAnalysisTab, 'push');
            app.Step2PascalstoDecibleswithapplicationofClipLevelButton.ButtonPushedFcn = createCallbackFcn(app, @Step2PascalstoDecibleswithapplicationofClipLevelButtonPushed, true);
            app.Step2PascalstoDecibleswithapplicationofClipLevelButton.Text = {'Step 2 - Pascals to Decibles'; 'with application of Clip Level'};
            app.Step3StructtoTimeTableButton = uibutton(app.DataAnalysisTab, 'push');
            app.Step3StructtoTimeTableButton.ButtonPushedFcn = createCallbackFcn(app, @Step3StructtoTimeTableButtonPushed, true);
            app.Step3StructtoTimeTableButton.Text = 'Step 3 - Struct to TimeTable';
            app.CompletedAnalysesListBoxLabel = uilabel(app.DataAnalysisTab);
            app.CompletedAnalysesListBoxLabel.Text = {'Completed'; 'Analyses'};
            app.CompletedAnalysesListBox = uilistbox(app.DataAnalysisTab);
            app.CompletedAnalysesListBox.Items = {};
            app.CompletedAnalysesListBox.Multiselect = 'on';
            app.CompletedAnalysesListBox.Value = {};
            app.ExternalExportPanel = uipanel(app.DataAnalysisTab);
            app.ExternalExportPanel.Title = 'External Export';
            app.ICESContinuousNoiseButton = uibutton(app.ExternalExportPanel, 'push');
            app.ICESContinuousNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ICESContinuousNoiseButtonPushed, true);
            app.ICESContinuousNoiseButton.Text = 'ICES Continuous Noise';
            app.ContactEmailEditFieldLabel = uilabel(app.ExternalExportPanel);
            app.ContactEmailEditFieldLabel.Text = 'Contact Email';
            app.ContactEmailEditField = uieditfield(app.ExternalExportPanel, 'text');
            app.EquipmentUpdateTab = uitab(app.TabGroup);
            app.EquipmentUpdateTab.Title = 'Equipment Update';
            app.LoggerTablePanel = uipanel(app.EquipmentUpdateTab);
            app.LoggerTablePanel.Title = 'Logger Table';
            app.ModelDropDown_2Label_2 = uilabel(app.LoggerTablePanel);
            app.ModelDropDown_2Label_2.Text = 'Model';
            app.ModelDropDown_2 = uidropdown(app.LoggerTablePanel);
            app.ModelDropDown_2.Items = {'ST600', 'ST500', 'ST300', 'ST4300', 'ST640', 'ST400', 'SM2M', 'SM3M', 'DSG', 'Sylence-p'};
            app.ModelDropDown_2.Value = 'ST600';
            app.BrandDropDownLabel = uilabel(app.LoggerTablePanel);
            app.BrandDropDownLabel.Text = 'Brand';
            app.BrandDropDown = uidropdown(app.LoggerTablePanel);
            app.BrandDropDown.Items = {'Ocean Instruments', 'Loggerhead Instruments', 'Wildlife Acoustics', 'RTSYS'};
            app.BrandDropDown.Value = 'Ocean Instruments';
            app.IDnumberEditFieldLabel = uilabel(app.LoggerTablePanel);
            app.IDnumberEditFieldLabel.Text = 'ID number';
            app.IDnumberEditField = uieditfield(app.LoggerTablePanel, 'text');
            app.PurchasedByEditFieldLabel = uilabel(app.LoggerTablePanel);
            app.PurchasedByEditFieldLabel.Text = 'Purchased By';
            app.PurchasedByEditField = uieditfield(app.LoggerTablePanel, 'text');
            app.RoughClipLevelEditFieldLabel = uilabel(app.LoggerTablePanel);
            app.RoughClipLevelEditFieldLabel.Text = 'Rough Clip Level';
            app.RoughClipLevelEditField = uieditfield(app.LoggerTablePanel, 'numeric');
            app.InputUpdateDataButton_logger = uibutton(app.LoggerTablePanel, 'push');
            app.InputUpdateDataButton_logger.ButtonPushedFcn = createCallbackFcn(app, @InputUpdateDataButton_loggerPushed, true);
            app.InputUpdateDataButton_logger.Text = 'Input/Update Data';
            app.UnitWorkingCheckBox = uicheckbox(app.LoggerTablePanel);
            app.UnitWorkingCheckBox.Text = 'Unit Working?';
            app.NotesEditFieldLabel = uilabel(app.LoggerTablePanel);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.NotesEditField = uieditfield(app.LoggerTablePanel, 'text');
            app.ReleaserTablePanel = uipanel(app.EquipmentUpdateTab);
            app.ReleaserTablePanel.Title = 'Releaser Table';
            app.ModelDropDown_3Label = uilabel(app.ReleaserTablePanel);
            app.ModelDropDown_3Label.Text = 'Model';
            app.ModelDropDown_3 = uidropdown(app.ReleaserTablePanel);
            app.ModelDropDown_3.Items = {'LRT', 'AR-60', 'Ascent-AR', 'R500'};
            app.ModelDropDown_3.Value = 'LRT';
            app.BrandDropDown_2Label = uilabel(app.ReleaserTablePanel);
            app.BrandDropDown_2Label.Text = 'Brand';
            app.BrandDropDown_2 = uidropdown(app.ReleaserTablePanel);
            app.BrandDropDown_2.Items = {'Sonardyne', 'Sub Sea Sonic', 'InnovaSea', 'Teledyne'};
            app.BrandDropDown_2.Value = 'Sonardyne';
            app.IDnumberEditField_2Label = uilabel(app.ReleaserTablePanel);
            app.IDnumberEditField_2Label.Text = 'ID number';
            app.IDnumberEditField_2 = uieditfield(app.ReleaserTablePanel, 'text');
            app.PurchasedByEditField_2Label = uilabel(app.ReleaserTablePanel);
            app.PurchasedByEditField_2Label.Text = 'Purchased By';
            app.PurchasedByEditField_2 = uieditfield(app.ReleaserTablePanel, 'text');
            app.InputUpdateDataButton_releaser = uibutton(app.ReleaserTablePanel, 'push');
            app.InputUpdateDataButton_releaser.ButtonPushedFcn = createCallbackFcn(app, @InputUpdateDataButton_releaserPushed, true);
            app.InputUpdateDataButton_releaser.Text = 'Input/Update Data';
            app.UnitWorkingCheckBox_2 = uicheckbox(app.ReleaserTablePanel);
            app.UnitWorkingCheckBox_2.Text = 'Unit Working?';
            app.NotesEditField_2Label = uilabel(app.ReleaserTablePanel);
            app.NotesEditField_2Label.Text = 'Notes';
            app.NotesEditField_2 = uieditfield(app.ReleaserTablePanel, 'text');
            app.SatelliteTagsPanel = uipanel(app.EquipmentUpdateTab);
            app.SatelliteTagsPanel.Title = 'Satellite Tags';
            app.LoadDatabaseButton = uibutton(app.LeftPanel, 'push');
            app.LoadDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatabaseButtonPushed, true);
            app.LoadDatabaseButton.Text = 'Load Database';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Plotting';
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.LogFileQAQCTab = uitab(app.TabGroup2);
            app.LogFileQAQCTab.Title = 'Log File QAQC';
            app.InternalBattPlot = uiaxes(app.LogFileQAQCTab);
            title(app.InternalBattPlot, 'Internal Battery Voltage')
            ylabel(app.InternalBattPlot, 'mV')
            zlabel(app.InternalBattPlot, 'Z')
            app.ExternalBattPlot = uiaxes(app.LogFileQAQCTab);
            title(app.ExternalBattPlot, 'External Battery Voltage')
            ylabel(app.ExternalBattPlot, 'mV')
            zlabel(app.ExternalBattPlot, 'Z')
            app.TempPlot = uiaxes(app.LogFileQAQCTab);
            title(app.TempPlot, 'Temperature')
            ylabel(app.TempPlot, 'C°')
            zlabel(app.TempPlot, 'Z')
            app.SampCountPlot = uiaxes(app.LogFileQAQCTab);
            title(app.SampCountPlot, 'Sample Count')
            zlabel(app.SampCountPlot, 'Z')
            app.ViewDataTab = uitab(app.TabGroup2);
            app.ViewDataTab.Title = 'View Data';
            app.UITable = uitable(app.ViewDataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ReportPlotsTab = uitab(app.TabGroup2);
            app.ReportPlotsTab.Title = 'Report Plots';
            app.ViolinButton = uibutton(app.ReportPlotsTab, 'push');
            app.ViolinButton.Text = 'Violin';
            app.GanttButton = uibutton(app.ReportPlotsTab, 'push');
            app.GanttButton.Text = 'Gantt';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DUNA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end